classdef AreaRectangulo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        medida      matlab.ui.control.DropDown
        calcular    matlab.ui.control.Button
        altura      matlab.ui.control.EditField
        base        matlab.ui.control.EditField
        text4       matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        uipanel1    matlab.ui.container.Panel
        txtMedida   matlab.ui.control.Label
        Txtcalculo  matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
            
            % Obtener valores de base y altura
            baseStr = app.base.Value;
            alturaStr = app.altura.Value;

            % Validar entrada numérica
            base = str2double(baseStr);
            altura = str2double(alturaStr);

            if isnan(base) || isnan(altura) || base <= 0 || altura <= 0
                uialert(app.figure1, 'Ingrese valores numéricos válidos mayores que cero para base y altura.', 'Error de entrada');
                return;
            end

            % Calcular área
            area = base * altura;

            % Mostrar resultado
            app.Txtcalculo.Value = num2str(area);

            % Validar unidad de medida seleccionada
            unidad = app.medida.Value;
            if strcmp(unidad, 'Unidad de Medida')
                uialert(app.figure1, 'Por favor, seleccione una unidad de medida antes de calcular.', 'Unidad no seleccionada');
                return;
            end
            
            % Mostrar unidad
            switch unidad
                case 'Milímetros'
                    app.txtMedida.Text = 'mm²';
                case 'Centimetros'
                    app.txtMedida.Text = 'cm²';
                case 'Metros'
                    app.txtMedida.Text = 'm²';
                case 'Kilometros'
                    app.txtMedida.Text = 'km²';
            end
            app.txtMedida.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AreaRectangulo';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'AREA';
            app.uipanel1.Tag = 'uipanel1';
            app.Txtcalculo = uieditfield(app.uipanel1, 'text');
            app.Txtcalculo.Tag = 'Txtcalculo';
            app.txtMedida = uilabel(app.uipanel1);
            app.txtMedida.Tag = 'txtMedida';
            app.txtMedida.Visible = 'off';
            app.txtMedida.Text = '';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'ÁREA DE UN RECTÁNGULO';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'BASE';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'ALTURA'; ''};
            app.base = uieditfield(app.figure1, 'text');
            app.base.Tag = 'Txtbase';
            app.altura = uieditfield(app.figure1, 'text');
            app.altura.Tag = 'Txtaltura';
            app.calcular = uibutton(app.figure1, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Tag = 'pushbutton1';
            app.calcular.Text = {'CALCULAR'; ''};
            app.medida = uidropdown(app.figure1);
            app.medida.Items = {'Unidad de Medida', 'Milímetros', 'Centimetros', 'Metros', 'Kilometros'};
            app.medida.Tag = 'popupmenu1';
            app.medida.Value = 'Unidad de Medida';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaRectangulo_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end