classdef Areas_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        popupmenu1   matlab.ui.control.DropDown
        pushbutton1  matlab.ui.control.Button
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        text6        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton2_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton2.
            
            close;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Areas_OpeningFcn(app, varargin)
            % --- Executes just before Areas is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            centerfig;
            axis off
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Areas (see VARARGIN)
            
            % Choose default command line output for Areas
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            option=get(handles.popupmenu1,'value');
            switch (option)
                case 1
            
                    s=imread('onza.jpg');
                    axes(handles.axes1);
                    image(s)
                    axis off
                case 2
            
            
                    s=imread('libra.png');
                    axes(handles.axes1);
                    image(s)
                    axis off
                case 3
            
            
                    s=imread('arroba.jpg');
                    axes(handles.axes1);
                    image(s)
                    axis off
                case 4
            
            
                    s=imread('quintal.jpg');
                    axes(handles.axes1);
                    image(s)
                    axis off
            
            end
            axes(handles.axes1);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
                % Obtener opción seleccionada del dropdown
                option = app.popupmenu1.Value;
            
                % Obtener valor numérico de edit3
                a = str2double(app.edit3.Value);
            
                % Validar que sea un número válido
                if isnan(a)
                    uialert(app.figure1, 'Ingrese un número válido', 'Error de entrada');
                    return
                end
            
                % Variable para resultado
                result = 0;
            
                % Realizar conversión según opción
                switch option
                    case 'Onzas'
                        result = a * 16;
                    case 'Libras'
                        result = a * 1;
                    case 'Arrobas'
                        result = a / 25;
                    case 'Quintales'
                        result = a / 100;
                end
            
                % Mostrar resultado en edit4
                app.edit4.Value = num2str(result);
        end

        % Value changed function: edit3
        function edit3ValueChanged(app, event)
                        value = app.edit3.Value;
                        
        end

        % Value changing function: edit3
        function edit3ValueChanging(app, event)
                        changingValue = event.Value;
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Areas';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CONVERSOR';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'UNIDAD';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'INGRESE LA CANTIDAD';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'RESULTADO';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3ValueChanged, true);
            app.edit3.ValueChangingFcn = createCallbackFcn(app, @edit3ValueChanging, true);
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Enable = 'off';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'CALCULAR';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Onzas', 'Libras', 'Arrobas', 'Quintales'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Onzas';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Areas_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Areas_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end