classdef Biseccion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        DATOSINICIALESPanel      matlab.ui.container.Panel
        TieneRaizEditField       matlab.ui.control.EditField
        TieneRaizEditFieldLabel  matlab.ui.control.Label
        RAIZEditField            matlab.ui.control.EditField
        RAIZEditFieldLabel       matlab.ui.control.Label
        Error                    matlab.ui.control.EditField
        sEditFieldLabel          matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        aEditFieldLabel          matlab.ui.control.Label
        fxEditField              matlab.ui.control.EditField
        fxEditFieldLabel         matlab.ui.control.Label
        TITULO                   matlab.ui.control.Label
        ITERACIONES              matlab.ui.control.ListBox
        CALCULAR                 matlab.ui.control.Button
        GRAFICA                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULAR
        function CALCULARButtonPushed(app, event)
            try
                % Leer y convertir el error permitido Ɛs (puede ser como 1E-25)
                es = str2double(app.Error.Value);
                if isnan(es) || es <= 0
                    uialert(app.figure1, 'Ingrese un valor válido y positivo para Ɛs.', 'Error');
                    return;
                end
        
                % Leer extremos del intervalo
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                if a >= b
                    uialert(app.figure1, 'El valor de "a" debe ser menor que "b".', 'Error');
                    return;
                end
        
                % Leer función y crear función anónima
                f_str = app.fxEditField.Value;
                f = str2func(['@(x)', f_str]);
        
                % Evaluar extremos
                fa = f(a);
                fb = f(b);
        
                % Verificar si hay raíz en el intervalo
                if fa * fb < 0
                    app.TieneRaizEditField.Value = 'SI';
                else
                    app.TieneRaizEditField.Value = 'NO';
                    uialert(app.figure1, 'No hay raíz en el intervalo dado (f(a) * f(b) > 0).', 'Sin Raíz');
                    return;
                end
        
                % Inicialización de variables
                iteraciones = {};
                xr_old = a;
                i = 1;
        
                while true
                    xr = (a + b) / 2;
                    fr = f(xr);
        
                    % Calcular error relativo aproximado
                    if i == 1
                        ea = NaN;
                    else
                        ea = abs((xr - xr_old) / xr);
                    end
        
                    iteraciones{end+1} = sprintf('Iteración %d: xr = %.10f, f(xr) = %.3e, ea = %.3e', ...
                                                 i, xr, fr, ea);
        
                    % Verificar convergencia
                    if i > 1 && ea < es
                        break;
                    end
        
                    % Actualizar límites del intervalo
                    if fa * fr < 0
                        b = xr;
                        fb = fr;
                    else
                        a = xr;
                        fa = fr;
                    end
        
                    xr_old = xr;
                    i = i + 1;
                end
        
                % Mostrar resultados
                app.RAIZEditField.Value = num2str(xr, '%.10f');
                app.ITERACIONES.Items = iteraciones;
        
                % Graficar función
                x_plot = linspace(app.aEditField.Value, app.bEditField.Value, 200);
                y_plot = arrayfun(f, x_plot);
                plot(app.GRAFICA, x_plot, y_plot, 'b', 'LineWidth', 2);
                hold(app.GRAFICA, 'on');
                yline(app.GRAFICA, 0, '--r'); % línea del eje x
                plot(app.GRAFICA, xr, f(xr), 'ko', 'MarkerFaceColor', 'g'); % punto raíz
                hold(app.GRAFICA, 'off');
                title(app.GRAFICA, 'Gráfica de f(x)');
                xlabel(app.GRAFICA, 'x');
                ylabel(app.GRAFICA, 'f(x)');
                grid(app.GRAFICA, 'on');
        
            catch ME
                uialert(app.figure1, ['Error: ' ME.message], 'Error en Cálculo');
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Biseccion';
            app.figure1.Tag = 'figure1';
            app.GRAFICA = uiaxes(app.figure1);
            app.GRAFICA.Tag = 'GRAFICA';
            app.CALCULAR = uibutton(app.figure1, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULAR.Tag = 'CALCULAR';
            app.CALCULAR.Text = 'CALCULAR RAIZ';
            app.ITERACIONES = uilistbox(app.figure1);
            app.ITERACIONES.Items = {'Iteraciones'};
            app.ITERACIONES.Tag = 'RAICES';
            app.ITERACIONES.Value = 'Iteraciones';
            app.TITULO = uilabel(app.figure1);
            app.TITULO.Tag = 'text11';
            app.TITULO.Text = 'MÉTODO DE BISECCIÓN';
            app.DATOSINICIALESPanel = uipanel(app.figure1);
            app.DATOSINICIALESPanel.Title = 'DATOS INICIALES:';
            app.fxEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.fxEditFieldLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.DATOSINICIALESPanel, 'text');
            app.aEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.aEditFieldLabel.Text = 'a=';
            app.aEditField = uieditfield(app.DATOSINICIALESPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.bEditFieldLabel.Text = 'b=';
            app.bEditField = uieditfield(app.DATOSINICIALESPanel, 'numeric');
            app.sEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.sEditFieldLabel.Text = 'Ɛs=';
            app.Error = uieditfield(app.DATOSINICIALESPanel, 'text');
            app.RAIZEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.RAIZEditFieldLabel.Text = 'RAIZ=';
            app.RAIZEditField = uieditfield(app.DATOSINICIALESPanel, 'text');
            app.TieneRaizEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.TieneRaizEditFieldLabel.Text = 'Tiene Raiz?';
            app.TieneRaizEditField = uieditfield(app.DATOSINICIALESPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Biseccion_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end