classdef Calcular_Perimetro_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        CALCULARButton               matlab.ui.control.Button
        PERIMETROEditField           matlab.ui.control.EditField
        PERIMETROEditFieldLabel      matlab.ui.control.Label
        UnidaddemedidaDropDown       matlab.ui.control.DropDown
        UnidaddemedidaDropDownLabel  matlab.ui.control.Label
        LADO4EditField               matlab.ui.control.NumericEditField
        LADO4EditFieldLabel          matlab.ui.control.Label
        LADO3EditField               matlab.ui.control.NumericEditField
        LADO3EditFieldLabel          matlab.ui.control.Label
        LADO2EditField               matlab.ui.control.NumericEditField
        LADO2EditFieldLabel          matlab.ui.control.Label
        LADO1EditField               matlab.ui.control.NumericEditField
        LADO1EditFieldLabel          matlab.ui.control.Label
        text2                        matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            % Leer los valores de los lados
            lado1 = app.LADO1EditField.Value;
            lado2 = app.LADO2EditField.Value;
            lado3 = app.LADO3EditField.Value;
            lado4 = app.LADO4EditField.Value;
        
            % Leer unidad de medida
            unidad = app.UnidaddemedidaDropDown.Value;
        
            % Verificar unidad seleccionada
            if isempty(unidad)
                uialert(app.figure1, 'Por favor, seleccione una unidad de medida.', 'Unidad faltante');
                return;
            end
        
            % Verificar que todos los lados sean positivos
            if any([lado1, lado2, lado3, lado4] <= 0)
                uialert(app.figure1, 'Todos los lados deben ser mayores que cero.', 'Lados inválidos');
                return;
            end
        
            % Calcular perímetro
            perimetro = lado1 + lado2 + lado3 + lado4;
        
            % Mostrar el resultado con la unidad
            app.PERIMETROEditField.Value = sprintf('%.2f %s', perimetro, unidad);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Calcular_Perimetro';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'PERIMETRO DE UN CUADRILATERO'; ''};
            app.Panel = uipanel(app.figure1);
            app.Panel.Title = 'Panel';
            app.LADO1EditFieldLabel = uilabel(app.Panel);
            app.LADO1EditFieldLabel.Text = 'LADO 1: ';
            app.LADO1EditField = uieditfield(app.Panel, 'numeric');
            app.LADO2EditFieldLabel = uilabel(app.Panel);
            app.LADO2EditFieldLabel.Text = 'LADO 2:';
            app.LADO2EditField = uieditfield(app.Panel, 'numeric');
            app.LADO3EditFieldLabel = uilabel(app.Panel);
            app.LADO3EditFieldLabel.Text = 'LADO 3:';
            app.LADO3EditField = uieditfield(app.Panel, 'numeric');
            app.LADO4EditFieldLabel = uilabel(app.Panel);
            app.LADO4EditFieldLabel.Text = 'LADO 4:';
            app.LADO4EditField = uieditfield(app.Panel, 'numeric');
            app.UnidaddemedidaDropDownLabel = uilabel(app.Panel);
            app.UnidaddemedidaDropDownLabel.Text = 'Unidad de medida: ';
            app.UnidaddemedidaDropDown = uidropdown(app.Panel);
            app.UnidaddemedidaDropDown.Items = {'', 'mm', 'cm', 'dm', 'm', 'km'};
            app.UnidaddemedidaDropDown.Value = '';
            app.PERIMETROEditFieldLabel = uilabel(app.Panel);
            app.PERIMETROEditFieldLabel.Text = 'PERIMETRO: ';
            app.PERIMETROEditField = uieditfield(app.Panel, 'text');
            app.CALCULARButton = uibutton(app.Panel, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcular_Perimetro_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end