classdef Cientifica_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        masfunciones  matlab.ui.control.Button
        RAIZCUBICA    matlab.ui.control.Button
        LITERAL       matlab.ui.control.Button
        RAIZ          matlab.ui.control.Button
        RAIZCUAD      matlab.ui.control.Button
        COMA          matlab.ui.control.Button
        RESULTADO     matlab.ui.control.Label
        IGUAL         matlab.ui.control.Button
        LOGNATURAL    matlab.ui.control.Button
        MENOS         matlab.ui.control.Button
        TANGENTE      matlab.ui.control.Button
        ABRIRBASICA   matlab.ui.control.Button
        POTENCIACUB   matlab.ui.control.Button
        POTENCIACUAD  matlab.ui.control.Button
        LOGARITMO     matlab.ui.control.Button
        POTENCIA      matlab.ui.control.Button
        PARENABIERTO  matlab.ui.control.Button
        SENO          matlab.ui.control.Button
        COSENO        matlab.ui.control.Button
        PI            matlab.ui.control.Button
        FACTORIAL     matlab.ui.control.Button
        PARENCERRADO  matlab.ui.control.Button
        GRAFICAR      matlab.ui.control.Button
        DIVIDIR       matlab.ui.control.Button
        BORRAR        matlab.ui.control.Button
        MULTIPLICAR   matlab.ui.control.Button
        NUEVE         matlab.ui.control.Button
        OCHO          matlab.ui.control.Button
        SIETE         matlab.ui.control.Button
        MAS           matlab.ui.control.Button
        TRES          matlab.ui.control.Button
        SEIS          matlab.ui.control.Button
        PUNTO         matlab.ui.control.Button
        DOS           matlab.ui.control.Button
        CINCO         matlab.ui.control.Button
        CERO          matlab.ui.control.Button
        UNO           matlab.ui.control.Button
        CUATRO        matlab.ui.control.Button
        GRAFICA       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function MAYORQUE_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in MAYORQUE.
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'>')
            set(handles.RESULTADO,'string',s);
        end
        
        function MENORQUE_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in MENORQUE.
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'<')
            set(handles.RESULTADO,'string',s);
        end
        
        function PORCIENTO_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in PORCIENTO.
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'%(')
            set(handles.RESULTADO,'string',s);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Cientifica_OpeningFcn(app, varargin)
            % --- Executes just before Cientifica is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>


            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Cientifica (see VARARGIN)
            
            % Choose default command line output for Cientifica
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: ABRIRBASICA
        function ABRIRBASICA_Callback(app, event)
            % --- Executes on button press in ABRIRBASICA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            CALCULADORAUMG
            close(Cientifica)
        end

        % Button pushed function: BORRAR
        function BORRAR_Callback(app, event)
            % --- Executes on button press in BORRAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.RESULTADO,'String','')
        end

        % Button pushed function: CERO
        function CERO_Callback(app, event)
            % --- Executes on button press in CERO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'0')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: CINCO
        function CINCO_Callback(app, event)
            % --- Executes on button press in CINCO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'5')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: COMA
        function COMA_Callback(app, event)
            % --- Executes on button press in COMA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,',')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: COSENO
        function COSENO_Callback(app, event)
            % --- Executes on button press in COSENO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'cos(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: CUATRO
        function CUATRO_Callback(app, event)
            % --- Executes on button press in CUATRO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'4')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: DIVIDIR
        function DIVIDIR_Callback(app, event)
            % --- Executes on button press in DIVIDIR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'/')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: DOS
        function DOS_Callback(app, event)
            % --- Executes on button press in DOS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'2')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: FACTORIAL
        function FACTORIAL_Callback(app, event)
            % --- Executes on button press in FACTORIAL.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            handles.memoria=num2cell(zeros(1,24))
            handles.i=0;
            handles.R=factorial(handles.NUM)
            set(handles.RESULTADO,'String',handles.R)
            guidata(hObject,handles)
        end

        % Button pushed function: GRAFICAR
        function GRAFICAR_Callback(app, event)
            % --- Executes on button press in GRAFICAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            axes(handles.GRAFICA);
            valor = get(handles.RESULTADO,'String');
            hold on
            ezplot(valor)
            grid
            hold off
        end

        % Button pushed function: IGUAL
        function IGUAL_Callback(app, event)
            % --- Executes on button press in IGUAL.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=eval(s)
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: LITERAL
        function LITERAL_Callback(app, event)
            % --- Executes on button press in LITERAL.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            
            s=strcat(s,'x')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: LOGARITMO
        function LOGARITMO_Callback(app, event)
            % --- Executes on button press in LOGARITMO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'log10(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: LOGNATURAL
        function LOGNATURAL_Callback(app, event)
            % --- Executes on button press in LOGNATURAL.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'log(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: MAS
        function MAS_Callback(app, event)
            % --- Executes on button press in MAS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'+')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: MENOS
        function MENOS_Callback(app, event)
            % --- Executes on button press in MENOS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'-')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: MULTIPLICAR
        function MULTIPLICAR_Callback(app, event)
            % --- Executes on button press in MULTIPLICAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'*')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: NUEVE
        function NUEVE_Callback(app, event)
            % --- Executes on button press in NUEVE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'9')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: OCHO
        function OCHO_Callback(app, event)
            % --- Executes on button press in OCHO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'8')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: PARENABIERTO
        function PARENABIERTO_Callback(app, event)
            % --- Executes on button press in PARENABIERTO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: PARENCERRADO
        function PARENCERRADO_Callback(app, event)
            % --- Executes on button press in PARENCERRADO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,')')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: PI
        function PI_Callback(app, event)
            % --- Executes on button press in PI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'pi')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: POTENCIACUAD
        function POTENCIACUAD_Callback(app, event)
            % --- Executes on button press in POTENCIACUAD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'^2')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: POTENCIACUB
        function POTENCIACUB_Callback(app, event)
            % --- Executes on button press in POTENCIACUB.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'^3')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: POTENCIA
        function POTENCIA_Callback(app, event)
            % --- Executes on button press in POTENCIA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'^')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: PUNTO
        function PUNTO_Callback(app, event)
            % --- Executes on button press in PUNTO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'.')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: RAIZCUAD
        function RAIZCUAD_Callback(app, event)
            % --- Executes on button press in RAIZCUAD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'sqrt(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: RAIZCUBICA
        function RAIZCUBICA_Callback(app, event)
            % --- Executes on button press in RAIZCUBICA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat('nthroot(',s,',3)')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: RAIZ
        function RAIZ_Callback(app, event)
            % --- Executes on button press in RAIZCUAD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'nthroot(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: SEIS
        function SEIS_Callback(app, event)
            % --- Executes on button press in SEIS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'6')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: SENO
        function SENO_Callback(app, event)
            % --- Executes on button press in SENO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'sin(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: SIETE
        function SIETE_Callback(app, event)
            % --- Executes on button press in SIETE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'7')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: TANGENTE
        function TANGENTE_Callback(app, event)
            % --- Executes on button press in TANGENTE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'tan(')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: TRES
        function TRES_Callback(app, event)
            % --- Executes on button press in TRES.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'3')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: UNO
        function UNO_Callback(app, event)
            % --- Executes on button press in UNO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            s = get (handles.RESULTADO,'string');
            s=strcat(s,'1')
            set(handles.RESULTADO,'string',s);
        end

        % Button pushed function: masfunciones
        function masfunciones_Callback(app, event)
            % --- Executes on button press in masfunciones.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            masOpciones_App
            close(Cientifica)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Cientifica';
            app.figure1.Tag = 'figure1';
            app.GRAFICA = uiaxes(app.figure1);
            app.GRAFICA.Tag = 'GRAFICA';
            app.CUATRO = uibutton(app.figure1, 'push');
            app.CUATRO.ButtonPushedFcn = createCallbackFcn(app, @CUATRO_Callback, true);
            app.CUATRO.Tag = 'CUATRO';
            app.CUATRO.Text = '4';
            app.UNO = uibutton(app.figure1, 'push');
            app.UNO.ButtonPushedFcn = createCallbackFcn(app, @UNO_Callback, true);
            app.UNO.Tag = 'UNO';
            app.UNO.Text = '1';
            app.CERO = uibutton(app.figure1, 'push');
            app.CERO.ButtonPushedFcn = createCallbackFcn(app, @CERO_Callback, true);
            app.CERO.Tag = 'CERO';
            app.CERO.Text = '0';
            app.CINCO = uibutton(app.figure1, 'push');
            app.CINCO.ButtonPushedFcn = createCallbackFcn(app, @CINCO_Callback, true);
            app.CINCO.Tag = 'CINCO';
            app.CINCO.Text = '5';
            app.DOS = uibutton(app.figure1, 'push');
            app.DOS.ButtonPushedFcn = createCallbackFcn(app, @DOS_Callback, true);
            app.DOS.Tag = 'DOS';
            app.DOS.Text = '2';
            app.PUNTO = uibutton(app.figure1, 'push');
            app.PUNTO.ButtonPushedFcn = createCallbackFcn(app, @PUNTO_Callback, true);
            app.PUNTO.Tag = 'PUNTO';
            app.PUNTO.Text = '.';
            app.SEIS = uibutton(app.figure1, 'push');
            app.SEIS.ButtonPushedFcn = createCallbackFcn(app, @SEIS_Callback, true);
            app.SEIS.Tag = 'SEIS';
            app.SEIS.Text = '6';
            app.TRES = uibutton(app.figure1, 'push');
            app.TRES.ButtonPushedFcn = createCallbackFcn(app, @TRES_Callback, true);
            app.TRES.Tag = 'TRES';
            app.TRES.Text = '3';
            app.MAS = uibutton(app.figure1, 'push');
            app.MAS.ButtonPushedFcn = createCallbackFcn(app, @MAS_Callback, true);
            app.MAS.Tag = 'MAS';
            app.MAS.Text = '+';
            app.SIETE = uibutton(app.figure1, 'push');
            app.SIETE.ButtonPushedFcn = createCallbackFcn(app, @SIETE_Callback, true);
            app.SIETE.Tag = 'SIETE';
            app.SIETE.Text = '7';
            app.OCHO = uibutton(app.figure1, 'push');
            app.OCHO.ButtonPushedFcn = createCallbackFcn(app, @OCHO_Callback, true);
            app.OCHO.Tag = 'OCHO';
            app.OCHO.Text = '8';
            app.NUEVE = uibutton(app.figure1, 'push');
            app.NUEVE.ButtonPushedFcn = createCallbackFcn(app, @NUEVE_Callback, true);
            app.NUEVE.Tag = 'NUEVE';
            app.NUEVE.Text = '9';
            app.MULTIPLICAR = uibutton(app.figure1, 'push');
            app.MULTIPLICAR.ButtonPushedFcn = createCallbackFcn(app, @MULTIPLICAR_Callback, true);
            app.MULTIPLICAR.Tag = 'MULTIPLICAR';
            app.MULTIPLICAR.Text = '*';
            app.BORRAR = uibutton(app.figure1, 'push');
            app.BORRAR.ButtonPushedFcn = createCallbackFcn(app, @BORRAR_Callback, true);
            app.BORRAR.Tag = 'BORRAR';
            app.BORRAR.Text = 'AC';
            app.DIVIDIR = uibutton(app.figure1, 'push');
            app.DIVIDIR.ButtonPushedFcn = createCallbackFcn(app, @DIVIDIR_Callback, true);
            app.DIVIDIR.Tag = 'DIVIDIR';
            app.DIVIDIR.Text = '/';
            app.GRAFICAR = uibutton(app.figure1, 'push');
            app.GRAFICAR.ButtonPushedFcn = createCallbackFcn(app, @GRAFICAR_Callback, true);
            app.GRAFICAR.Tag = 'GRAFICAR';
            app.GRAFICAR.Text = 'Graficar';
            app.PARENCERRADO = uibutton(app.figure1, 'push');
            app.PARENCERRADO.ButtonPushedFcn = createCallbackFcn(app, @PARENCERRADO_Callback, true);
            app.PARENCERRADO.Tag = 'PARENCERRADO';
            app.PARENCERRADO.Text = ')';
            app.FACTORIAL = uibutton(app.figure1, 'push');
            app.FACTORIAL.ButtonPushedFcn = createCallbackFcn(app, @FACTORIAL_Callback, true);
            app.FACTORIAL.Tag = 'FACTORIAL';
            app.FACTORIAL.Text = 'x!';
            app.PI = uibutton(app.figure1, 'push');
            app.PI.ButtonPushedFcn = createCallbackFcn(app, @PI_Callback, true);
            app.PI.Tag = 'PI';
            app.PI.Text = 'π';
            app.COSENO = uibutton(app.figure1, 'push');
            app.COSENO.ButtonPushedFcn = createCallbackFcn(app, @COSENO_Callback, true);
            app.COSENO.Tag = 'COSENO';
            app.COSENO.Text = 'cos';
            app.SENO = uibutton(app.figure1, 'push');
            app.SENO.ButtonPushedFcn = createCallbackFcn(app, @SENO_Callback, true);
            app.SENO.Tag = 'SENO';
            app.SENO.Text = 'sin';
            app.PARENABIERTO = uibutton(app.figure1, 'push');
            app.PARENABIERTO.ButtonPushedFcn = createCallbackFcn(app, @PARENABIERTO_Callback, true);
            app.PARENABIERTO.Tag = 'PARENABIERTO';
            app.PARENABIERTO.Text = '(';
            app.POTENCIA = uibutton(app.figure1, 'push');
            app.POTENCIA.ButtonPushedFcn = createCallbackFcn(app, @POTENCIA_Callback, true);
            app.POTENCIA.Tag = 'POTENCIA';
            app.POTENCIA.Text = '^';
            app.LOGARITMO = uibutton(app.figure1, 'push');
            app.LOGARITMO.ButtonPushedFcn = createCallbackFcn(app, @LOGARITMO_Callback, true);
            app.LOGARITMO.Tag = 'LOGARITMO';
            app.LOGARITMO.Text = 'log';
            app.POTENCIACUAD = uibutton(app.figure1, 'push');
            app.POTENCIACUAD.ButtonPushedFcn = createCallbackFcn(app, @POTENCIACUAD_Callback, true);
            app.POTENCIACUAD.Tag = 'POTENCIACUAD';
            app.POTENCIACUAD.Text = 'x²';
            app.POTENCIACUB = uibutton(app.figure1, 'push');
            app.POTENCIACUB.ButtonPushedFcn = createCallbackFcn(app, @POTENCIACUB_Callback, true);
            app.POTENCIACUB.Tag = 'POTENCIACUB';
            app.POTENCIACUB.Text = 'x³';
            app.ABRIRBASICA = uibutton(app.figure1, 'push');
            app.ABRIRBASICA.ButtonPushedFcn = createCallbackFcn(app, @ABRIRBASICA_Callback, true);
            app.ABRIRBASICA.Tag = 'ABRIRBASICA';
            app.ABRIRBASICA.Text = 'BASICA';
            app.TANGENTE = uibutton(app.figure1, 'push');
            app.TANGENTE.ButtonPushedFcn = createCallbackFcn(app, @TANGENTE_Callback, true);
            app.TANGENTE.Tag = 'TANGENTE';
            app.TANGENTE.Text = 'tan';
            app.MENOS = uibutton(app.figure1, 'push');
            app.MENOS.ButtonPushedFcn = createCallbackFcn(app, @MENOS_Callback, true);
            app.MENOS.Tag = 'MENOS';
            app.MENOS.Text = '-';
            app.LOGNATURAL = uibutton(app.figure1, 'push');
            app.LOGNATURAL.ButtonPushedFcn = createCallbackFcn(app, @LOGNATURAL_Callback, true);
            app.LOGNATURAL.Tag = 'LOGNATURAL';
            app.LOGNATURAL.Text = 'ln';
            app.IGUAL = uibutton(app.figure1, 'push');
            app.IGUAL.ButtonPushedFcn = createCallbackFcn(app, @IGUAL_Callback, true);
            app.IGUAL.Tag = 'IGUAL';
            app.IGUAL.Text = '=';
            app.RESULTADO = uilabel(app.figure1);
            app.RESULTADO.Tag = 'RESULTADO';
            app.RESULTADO.Text = '';
            app.COMA = uibutton(app.figure1, 'push');
            app.COMA.ButtonPushedFcn = createCallbackFcn(app, @COMA_Callback, true);
            app.COMA.Tag = 'COMA';
            app.COMA.Text = ',';
            app.RAIZCUAD = uibutton(app.figure1, 'push');
            app.RAIZCUAD.ButtonPushedFcn = createCallbackFcn(app, @RAIZCUAD_Callback, true);
            app.RAIZCUAD.Tag = 'RAIZCUAD';
            app.RAIZCUAD.Text = '²√';
            app.RAIZ = uibutton(app.figure1, 'push');
            app.RAIZ.ButtonPushedFcn = createCallbackFcn(app, @RAIZ_Callback, true);
            app.RAIZ.Tag = 'RAIZ';
            app.RAIZ.Text = 'x√';
            app.LITERAL = uibutton(app.figure1, 'push');
            app.LITERAL.ButtonPushedFcn = createCallbackFcn(app, @LITERAL_Callback, true);
            app.LITERAL.Tag = 'LITERAL';
            app.LITERAL.Text = 'X';
            app.RAIZCUBICA = uibutton(app.figure1, 'push');
            app.RAIZCUBICA.ButtonPushedFcn = createCallbackFcn(app, @RAIZCUBICA_Callback, true);
            app.RAIZCUBICA.Tag = 'RAIZCUBICA';
            app.RAIZCUBICA.Text = '³√';
            app.masfunciones = uibutton(app.figure1, 'push');
            app.masfunciones.ButtonPushedFcn = createCallbackFcn(app, @masfunciones_Callback, true);
            app.masfunciones.Tag = 'masfunciones';
            app.masfunciones.Text = 'MÁS FUNCIONES';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cientifica_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Cientifica_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end