classdef ConvertidorTemperatura_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        ConversinPanel               matlab.ui.container.Panel
        KelvinEditField              matlab.ui.control.EditField
        KelvinEditFieldLabel         matlab.ui.control.Label
        FaherenheitEditField         matlab.ui.control.EditField
        FaherenheitLabel             matlab.ui.control.Label
        CelsiusEditField             matlab.ui.control.EditField
        CelsiusLabel                 matlab.ui.control.Label
        CALCULARButton               matlab.ui.control.Button
        TemperaturaaConvertirPanel   matlab.ui.container.Panel
        UnidaddeMedidaDropDown       matlab.ui.control.DropDown
        UnidaddeMedidaDropDownLabel  matlab.ui.control.Label
        GradosEditField              matlab.ui.control.NumericEditField
        GradosEditFieldLabel         matlab.ui.control.Label
        text2                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            % Obtener el valor ingresado
            grados = app.GradosEditField.Value;
            unidad = app.UnidaddeMedidaDropDown.Value;
        
            % Validaciones
            if isempty(grados)
                uialert(app.figure1, 'Por favor ingrese un valor numérico para los grados.', 'Dato requerido');
                return;
            end
        
            if isempty(unidad)
                uialert(app.figure1, 'Por favor seleccione una unidad de medida.', 'Dato requerido');
                return;
            end
        
            % Inicializar variables
            C = 0;
            F = 0;
            K = 0;
        
            % Conversión según unidad de entrada
            switch unidad
                case 'Celsius'
                    C = grados;
                    F = (C * 9/5) + 32;
                    K = C + 273.15;
        
                case 'Fahrenheit'
                    F = grados;
                    C = (F - 32) * 5/9;
                    K = C + 273.15;
        
                case 'Kelvin'
                    K = grados;
                    C = K - 273.15;
                    F = (C * 9/5) + 32;
        
                otherwise
                    uialert(app.figure1, 'Unidad no válida.', 'Error');
                    return;
            end
        
            % Mostrar resultados con dos decimales
            app.CelsiusEditField.Value = sprintf('%.2f °C', C);
            app.FaherenheitEditField.Value = sprintf('%.2f °F', F);
            app.KelvinEditField.Value = sprintf('%.2f K', K);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ConvertidorTemperatura';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CONVERTIDOR DE TEMPERATURAS';
            app.TemperaturaaConvertirPanel = uipanel(app.figure1);
            app.TemperaturaaConvertirPanel.Title = 'Temperatura a Convertir';
            app.GradosEditFieldLabel = uilabel(app.TemperaturaaConvertirPanel);
            app.GradosEditFieldLabel.Text = 'Grados: ';
            app.GradosEditField = uieditfield(app.TemperaturaaConvertirPanel, 'numeric');
            app.UnidaddeMedidaDropDownLabel = uilabel(app.TemperaturaaConvertirPanel);
            app.UnidaddeMedidaDropDownLabel.Text = 'Unidad de Medida';
            app.UnidaddeMedidaDropDown = uidropdown(app.TemperaturaaConvertirPanel);
            app.UnidaddeMedidaDropDown.Items = {'', 'Celsius', 'Fahrenheit', 'Kelvin'};
            app.UnidaddeMedidaDropDown.Value = '';
            app.ConversinPanel = uipanel(app.figure1);
            app.ConversinPanel.Title = 'Conversión';
            app.CALCULARButton = uibutton(app.ConversinPanel, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.CelsiusLabel = uilabel(app.ConversinPanel);
            app.CelsiusLabel.Text = 'Celsius: ';
            app.CelsiusEditField = uieditfield(app.ConversinPanel, 'text');
            app.FaherenheitLabel = uilabel(app.ConversinPanel);
            app.FaherenheitLabel.Text = 'Faherenheit:';
            app.FaherenheitEditField = uieditfield(app.ConversinPanel, 'text');
            app.KelvinEditFieldLabel = uilabel(app.ConversinPanel);
            app.KelvinEditFieldLabel.Text = 'Kelvin:';
            app.KelvinEditField = uieditfield(app.ConversinPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvertidorTemperatura_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end