classdef EcuLine_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        RESULTADOEditField              matlab.ui.control.EditField
        RESULTADOEditFieldLabel         matlab.ui.control.Label
        resolver                        matlab.ui.control.Button
        IngreselaEcuacinEditField       matlab.ui.control.EditField
        IngreselaEcuacinEditFieldLabel  matlab.ui.control.Label
        text2                           matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resolver
        function resolverButtonPushed(app, event)
            try
                syms x y; % Declarar símbolos por si la ecuación contiene x o y
                ecuacionStr = app.IngreselaEcuacinEditField.Value;

                % Evaluar la ecuación ingresada por el usuario
                ecuacionSimbolica = eval(ecuacionStr);

                % Resolver la ecuación
                resultado = solve(ecuacionSimbolica, 'Real', true);

                % Mostrar resultado como texto
                app.RESULTADOEditField.Value = char(resultado);
            catch ME
                app.RESULTADOEditField.Value = ['Error: ', ME.message];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Ecuaciones Lineales';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CALCULADORA DE ECUACIONES LINEALES';
            app.Panel = uipanel(app.figure1);
            app.IngreselaEcuacinEditFieldLabel = uilabel(app.Panel);
            app.IngreselaEcuacinEditFieldLabel.Text = 'Ingrese la Ecuación: ';
            app.IngreselaEcuacinEditField = uieditfield(app.Panel, 'text');
            app.resolver = uibutton(app.Panel, 'push');
            app.resolver.ButtonPushedFcn = createCallbackFcn(app, @resolverButtonPushed, true);
            app.resolver.Tag = 'resolver';
            app.resolver.Text = 'Resolver';
            app.RESULTADOEditFieldLabel = uilabel(app.Panel);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO: ';
            app.RESULTADOEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuLine_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end