classdef Limites_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LIMITESUIFigure                matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        mensajeLabel                   matlab.ui.control.Label
        resultadoLabel                 matlab.ui.control.Label
        calcularButton                 matlab.ui.control.Button
        direccionDropDown              matlab.ui.control.DropDown
        DireccindellmiteDropDownLabel  matlab.ui.control.Label
        xValueField                    matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        fEditField                     matlab.ui.control.EditField
        fxLabel                        matlab.ui.control.Label
        LIMITESLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcularButton
        function calcularButtonPushed(app, event)
            try
               
                if isempty(funcionStr)
                    app.resultadoLabel.Text = 'Error: Ingrese una función.';
                    return;
                end
                
                if isempty(xValor)
                    app.resultadoLabel.Text = 'Error: Ingrese el valor de x.';
                    return;
                end

                syms x
                funcionStr = app.fEditField.Value;
                xValor = app.xValueField.Value;
                direccion = app.direccionDropDown.Value;
        
                f = str2sym(funcionStr);  % Convierte string a expresión simbólica
        
                % Decide la dirección del límite
                switch direccion
                    case 'Bilateral'
                        limite = limit(f, x, xValor);
                    case 'Derecha'
                        limite = limit(f, x, xValor, 'right');
                    case 'Izquierda'
                        limite = limit(f, x, xValor, 'left');
                end
        
                % Mostrar el resultado
                app.resultadoLabel.Text = ['Límite = ', char(limite)];
            catch e
                app.resultadoLabel.Text = ['Error: ', e.message];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LIMITESUIFigure = uifigure('Visible', 'off');
            app.LIMITESUIFigure.Name = 'LIMITES';
            app.LIMITESLabel = uilabel(app.LIMITESUIFigure);
            app.LIMITESLabel.Text = 'LIMITES';
            app.Panel = uipanel(app.LIMITESUIFigure);
            app.fxLabel = uilabel(app.Panel);
            app.fxLabel.Text = 'f(x)=';
            app.fEditField = uieditfield(app.Panel, 'text');
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x→';
            app.xValueField = uieditfield(app.Panel, 'numeric');
            app.DireccindellmiteDropDownLabel = uilabel(app.Panel);
            app.DireccindellmiteDropDownLabel.Text = {'Dirección del límite:'; ''};
            app.direccionDropDown = uidropdown(app.Panel);
            app.direccionDropDown.Items = {'Bilateral', 'Derecha', 'Izquierda'};
            app.direccionDropDown.Value = 'Bilateral';
            app.calcularButton = uibutton(app.Panel, 'push');
            app.calcularButton.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcularButton.Text = 'Calcular Límite';
            app.resultadoLabel = uilabel(app.Panel);
            app.resultadoLabel.Text = '';
            app.mensajeLabel = uilabel(app.Panel);
            app.mensajeLabel.Text = 'Resultado:';
            % Show the figure after all components are created
            app.LIMITESUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Limites_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LIMITESUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LIMITESUIFigure)
        end
    end
end