classdef MetodoSecanteModificada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ListadeIteracionesListBox  matlab.ui.control.ListBox
        METODODELASECANTEMODIFICADALabel  matlab.ui.control.Label
        RAIZTextArea               matlab.ui.control.TextArea
        RAIZTextAreaLabel          matlab.ui.control.Label
        LimpiarButton              matlab.ui.control.Button
        CalcularButton             matlab.ui.control.Button
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        XoEditField                matlab.ui.control.NumericEditField
        XoEditFieldLabel           matlab.ui.control.Label
        FuncionEditField           matlab.ui.control.EditField
        FuncionEditFieldLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
try
    % Obtener datos de los campos
    f_str = strtrim(app.FuncionEditField.Value); % trim para quitar espacios
    x0 = app.XoEditField.Value;
    x1 = app.X1EditField.Value;
    tol = app.ToleranciaEditField.Value;
    maxIter = app.IteracionesEditField.Value;

    % Limpiar lista de iteraciones al iniciar
    app.ListadeIteracionesListBox.Items = {};

    % Validar que la función no esté vacía
    if isempty(f_str)
        app.RAIZTextArea.Value = "Error: El campo de la función no puede estar vacío.";
        return;
    end

    % Validaciones restantes
    if tol <= 0 || tol >= 1
        app.RAIZTextArea.Value = "Error: La tolerancia debe ser mayor que 0 y menor que 1.";
        return;
    end
    if maxIter <= 0 || floor(maxIter) ~= maxIter
        app.RAIZTextArea.Value = "Error: El número máximo de iteraciones debe ser un entero positivo.";
        return;
    end
    if x0 == x1
        app.RAIZTextArea.Value = "Error: X0 y X1 deben ser diferentes.";
        return;
    end

    % Convertir cadena a función anónima
    f = str2func(['@(x)', f_str]);

    % Probar que la función se puede evaluar correctamente
    try
        % Evaluar la función en un punto ejemplo (x=1)
        test = f(1);

        % Verificar que el resultado sea numérico y válido
        if ~isnumeric(test) || isnan(test) || isinf(test)
            error('La función no devuelve un valor numérico válido.');
        end
    catch
        app.RAIZTextArea.Value = [
            "Error: Función inválida. Por favor ingrese una función válida de x." newline ...
            "Ejemplo: sin(x) + x^2 - 3"
        ];
        return;
    end

    % Iteración del método de la secante modificada
    for i = 1:maxIter
        fx0 = f(x0);
        fx1 = f(x1);
        if fx1 - fx0 == 0
            app.RAIZTextArea.Value = "División por cero en iteración " + i;
            return;
        end
        x2 = x1 - fx1 * (x1 - x0) / (fx1 - fx0);
        error = abs(x2 - x1);

        % Agregar iteración a la lista
        nuevaLinea = sprintf('Iter %d: x = %.8f, error = %.8f', i, x2, error);
        app.ListadeIteracionesListBox.Items{end+1} = nuevaLinea;

        if error < tol
            % Convergió
            app.RAIZTextArea.Value = num2str(x2, '%.8f');

            % Limpiar gráfico anterior
            cla(app.UIAxes);

            % Graficar función y raíz
            fplot(app.UIAxes, f, [x2-5 x2+5]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x2, f(x2), 'ro', 'MarkerSize', 10, 'DisplayName', 'Raíz');
            legend(app.UIAxes, 'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            return;
        end
        x0 = x1;
        x1 = x2;
    end

    % Si llega aquí no convergió
    app.RAIZTextArea.Value = "No se alcanzó la convergencia en " + maxIter + " iteraciones.";

catch e
    app.RAIZTextArea.Value = "Error inesperado: " + e.message;
end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
app.FuncionEditField.Value = '';
app.XoEditField.Value = 0;
app.X1EditField.Value = 0;
app.ToleranciaEditField.Value = 0;
app.IteracionesEditField.Value = 0;
app.RAIZTextArea.Value = '';
cla(app.UIAxes); % limpiar gráfico
app.ListadeIteracionesListBox.Items = {}; % limpiar lista de iteraciones
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion:';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.XoEditFieldLabel = uilabel(app.UIFigure);
            app.XoEditFieldLabel.Text = 'Xo:';
            app.XoEditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1:';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia:';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones:';
            app.IteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.RAIZTextAreaLabel = uilabel(app.UIFigure);
            app.RAIZTextAreaLabel.Text = 'RAIZ:';
            app.RAIZTextArea = uitextarea(app.UIFigure);
            app.METODODELASECANTEMODIFICADALabel = uilabel(app.UIFigure);
            app.METODODELASECANTEMODIFICADALabel.Interpreter = 'tex';
            app.METODODELASECANTEMODIFICADALabel.Text = 'METODO DE LA SECANTE MODIFICADA';
            app.ListadeIteracionesListBox = uilistbox(app.UIFigure);
            app.ListadeIteracionesListBox.Items = {};
            app.ListadeIteracionesListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoSecanteModificada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end