classdef Peso_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        ConversinPanel               matlab.ui.container.Panel
        RESULTADOEditField           matlab.ui.control.EditField
        RESULTADOEditFieldLabel      matlab.ui.control.Label
        ConvertiraDropDown           matlab.ui.control.DropDown
        ConvertiraDropDownLabel      matlab.ui.control.Label
        CONVERTIRButton              matlab.ui.control.Button
        CantidadesinicialesPanel     matlab.ui.container.Panel
        UnidaddemedidaDropDown       matlab.ui.control.DropDown
        UnidaddemedidaDropDownLabel  matlab.ui.control.Label
        PesoEditField                matlab.ui.control.NumericEditField
        PesoEditFieldLabel           matlab.ui.control.Label
        titulo                       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONVERTIRButton
        function CONVERTIRButtonPushed(app, event)
            peso = app.PesoEditField.Value;
            unidadOrigen = app.UnidaddemedidaDropDown.Value;
            unidadDestino = app.ConvertiraDropDown.Value;
        
            % Validar entradas
            if isempty(unidadOrigen) || isempty(unidadDestino) || peso <= 0
                app.RESULTADOEditField.Value = 'Ingrese un peso válido y seleccione ambas unidades.';
                return;
            end
        
            if strcmp(unidadOrigen, '') || strcmp(unidadDestino, '')
                app.RESULTADOEditField.Value = 'Seleccione ambas unidades de medida.';
                return;
            end
        
            % Convertir unidad de origen a onzas (unidad base)
            switch unidadOrigen
                case 'Onzas'
                    onzas = peso;
                case 'Libras'
                    onzas = peso * 16;
                case 'Arrobas'
                    onzas = peso * 400;
                case 'Quintales'
                    onzas = peso * 1600;
                otherwise
                    app.RESULTADOEditField.Value = 'Unidad de origen no válida.';
                    return;
            end
        
            % Convertir onzas a unidad destino
            switch unidadDestino
                case 'Onzas'
                    resultado = onzas;
                case 'Libras'
                    resultado = onzas / 16;
                case 'Arrobas'
                    resultado = onzas / 400;
                case 'Quintales'
                    resultado = onzas / 1600;
                otherwise
                    app.RESULTADOEditField.Value = 'Unidad destino no válida.';
                    return;
            end
        
            % Mostrar resultado con dos decimales
            app.RESULTADOEditField.Value = sprintf('%.2f %s', resultado, unidadDestino);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Areas';
            app.figure1.Tag = 'figure1';
            app.titulo = uilabel(app.figure1);
            app.titulo.Tag = 'text2';
            app.titulo.Text = 'CONVERSOR DE PESOS';
            app.CantidadesinicialesPanel = uipanel(app.figure1);
            app.CantidadesinicialesPanel.Title = 'Cantidades iniciales';
            app.PesoEditFieldLabel = uilabel(app.CantidadesinicialesPanel);
            app.PesoEditFieldLabel.Text = 'Peso:';
            app.PesoEditField = uieditfield(app.CantidadesinicialesPanel, 'numeric');
            app.UnidaddemedidaDropDownLabel = uilabel(app.CantidadesinicialesPanel);
            app.UnidaddemedidaDropDownLabel.Text = 'Unidad de medida';
            app.UnidaddemedidaDropDown = uidropdown(app.CantidadesinicialesPanel);
            app.UnidaddemedidaDropDown.Items = {'', 'Onzas', 'Libras', 'Arrobas', 'Quintales'};
            app.UnidaddemedidaDropDown.Value = '';
            app.ConversinPanel = uipanel(app.figure1);
            app.ConversinPanel.Title = 'Conversión';
            app.CONVERTIRButton = uibutton(app.ConversinPanel, 'push');
            app.CONVERTIRButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTIRButtonPushed, true);
            app.CONVERTIRButton.Text = 'CONVERTIR';
            app.ConvertiraDropDownLabel = uilabel(app.ConversinPanel);
            app.ConvertiraDropDownLabel.Text = 'Convertir a :';
            app.ConvertiraDropDown = uidropdown(app.ConversinPanel);
            app.ConvertiraDropDown.Items = {'', 'Onzas', 'Libras', 'Arrobas', 'Quintales'};
            app.ConvertiraDropDown.Value = '';
            app.RESULTADOEditFieldLabel = uilabel(app.ConversinPanel);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO: ';
            app.RESULTADOEditField = uieditfield(app.ConversinPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peso_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end