classdef AreaCuadrado_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Mostrar                    matlab.ui.control.Label
        ResultadoLabel             matlab.ui.control.Label
        CALCULARButton             matlab.ui.control.Button
        MedidadelosladosEditField  matlab.ui.control.NumericEditField
        MedidadelosladosLabel      matlab.ui.control.Label
        UnidaddeMedidaDropDown     matlab.ui.control.DropDown
        UnidaddeMedidaLabel        matlab.ui.control.Label
        titulo                     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            % Obtener los valores ingresados
            lado = app.MedidadelosladosEditField.Value;
            unidad = app.UnidaddeMedidaDropDown.Value;
        
            % Validar entrada
            if isempty(lado) || lado <= 0 || isempty(unidad)
                uialert(app.figure1, 'Por favor ingrese una medida válida y seleccione una unidad.', 'Error');
                return;
            end
        
            % Calcular el área
            area = lado^2;
        
            % Mostrar el resultado en la etiqueta Mostrar
            app.Mostrar.Text = sprintf('Área: %.2f %s²', area, unidad);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AreaCuadrado';
            app.figure1.Tag = 'figure1';
            app.titulo = uilabel(app.figure1);
            app.titulo.Tag = 'text2';
            app.titulo.Text = 'ÁREA DE UN CUADRADO';
            app.Panel = uipanel(app.figure1);
            app.UnidaddeMedidaLabel = uilabel(app.Panel);
            app.UnidaddeMedidaLabel.Text = 'Unidad de Medida:';
            app.UnidaddeMedidaDropDown = uidropdown(app.Panel);
            app.UnidaddeMedidaDropDown.Items = {'', 'mm', 'cm', 'm', 'km'};
            app.UnidaddeMedidaDropDown.Value = '';
            app.MedidadelosladosLabel = uilabel(app.Panel);
            app.MedidadelosladosLabel.Text = 'Medida de los lados: ';
            app.MedidadelosladosEditField = uieditfield(app.Panel, 'numeric');
            app.CALCULARButton = uibutton(app.Panel, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Text = 'Resultado:';
            app.Mostrar = uilabel(app.Panel);
            app.Mostrar.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaCuadrado_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end