classdef AreaRectangulo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        RESULTADOEditField           matlab.ui.control.EditField
        RESULTADOEditFieldLabel      matlab.ui.control.Label
        AlturaEditField              matlab.ui.control.NumericEditField
        AlturaEditFieldLabel         matlab.ui.control.Label
        BaseEditField                matlab.ui.control.NumericEditField
        BaseEditFieldLabel           matlab.ui.control.Label
        calcular                     matlab.ui.control.Button
        UnidaddeMedidaDropDown       matlab.ui.control.DropDown
        UnidaddeMedidaDropDownLabel  matlab.ui.control.Label
        text2                        matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
                % Obtener valores de base y altura
            base = app.BaseEditField.Value;
            altura = app.AlturaEditField.Value;
        
            % Validar entrada numérica
            if isempty(base) || isempty(altura) || base <= 0 || altura <= 0
                uialert(app.figure1, 'Ingrese valores numéricos válidos mayores que cero para base y altura.', 'Error de entrada');
                return;
            end
        
            % Calcular área
            area = base * altura;
        
            % Validar unidad de medida seleccionada
            unidad = app.UnidaddeMedidaDropDown.Value;
            if strcmp(unidad, '')
                uialert(app.figure1, 'Por favor, seleccione una unidad de medida antes de calcular.', 'Unidad no seleccionada');
                return;
            end
        
            % Crear cadena de resultado con unidad
            resultado = sprintf('%.2f %s²', area, unidad);
        
            % Mostrar resultado en el campo correspondiente
            app.RESULTADOEditField.Value = resultado;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AreaRectangulo';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'ÁREA DE UN RECTÁNGULO';
            app.Panel = uipanel(app.figure1);
            app.UnidaddeMedidaDropDownLabel = uilabel(app.Panel);
            app.UnidaddeMedidaDropDownLabel.Text = 'Unidad de Medida:';
            app.UnidaddeMedidaDropDown = uidropdown(app.Panel);
            app.UnidaddeMedidaDropDown.Items = {'', 'mm', 'cm', 'm', 'km'};
            app.UnidaddeMedidaDropDown.Value = '';
            app.calcular = uibutton(app.Panel, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Text = {'CALCULAR'; ''};
            app.BaseEditFieldLabel = uilabel(app.Panel);
            app.BaseEditFieldLabel.Text = 'Base:';
            app.BaseEditField = uieditfield(app.Panel, 'numeric');
            app.AlturaEditFieldLabel = uilabel(app.Panel);
            app.AlturaEditFieldLabel.Text = 'Altura:';
            app.AlturaEditField = uieditfield(app.Panel, 'numeric');
            app.RESULTADOEditFieldLabel = uilabel(app.Panel);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO:';
            app.RESULTADOEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaRectangulo_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end