classdef Areatriangulorectan_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        RESULTADOEditField           matlab.ui.control.EditField
        REAEditFieldLabel            matlab.ui.control.Label
        CALCULARButton               matlab.ui.control.Button
        UnidaddeMedidaDropDown       matlab.ui.control.DropDown
        UnidaddeMedidaDropDownLabel  matlab.ui.control.Label
        ALTLURAEditField             matlab.ui.control.NumericEditField
        ALTLURAEditFieldLabel        matlab.ui.control.Label
        BASEEditField                matlab.ui.control.NumericEditField
        BASEEditFieldLabel           matlab.ui.control.Label
        text6                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
                % Obtener la base y altura desde los campos numéricos
            base = app.BASEEditField.Value;
            altura = app.ALTLURAEditField.Value;
            
            % Verificar si la unidad está seleccionada
            unidad = app.UnidaddeMedidaDropDown.Value;
            
            % Validación: asegurar que todos los datos estén presentes y válidos
            if isempty(base) || isempty(altura) || base <= 0 || altura <= 0
                uialert(app.figure1, 'La base y la altura deben ser mayores que cero.', 'Error de entrada');
                return;
            end
            
            if isempty(unidad)
                uialert(app.figure1, 'Debe seleccionar una unidad de medida.', 'Unidad no seleccionada');
                return;
            end
            
            % Calcular el área
            area = (base * altura) / 2;
            
            % Mostrar el resultado con unidad al cuadrado
            app.RESULTADOEditField.Value = sprintf('%.2f %s²', area, unidad);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Areatriangulorectan';
            app.figure1.Tag = 'figure1';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = {'ÁREA TRIÁNGULO RECTÁNGULO'; ''};
            app.Panel = uipanel(app.figure1);
            app.BASEEditFieldLabel = uilabel(app.Panel);
            app.BASEEditFieldLabel.Text = 'BASE:';
            app.BASEEditField = uieditfield(app.Panel, 'numeric');
            app.ALTLURAEditFieldLabel = uilabel(app.Panel);
            app.ALTLURAEditFieldLabel.Text = 'ALTLURA: ';
            app.ALTLURAEditField = uieditfield(app.Panel, 'numeric');
            app.UnidaddeMedidaDropDownLabel = uilabel(app.Panel);
            app.UnidaddeMedidaDropDownLabel.Text = 'Unidad de Medida:';
            app.UnidaddeMedidaDropDown = uidropdown(app.Panel);
            app.UnidaddeMedidaDropDown.Items = {'', 'Milímetros', 'Centimetros', 'Metros', 'Kilometros'};
            app.UnidaddeMedidaDropDown.Value = '';
            app.CALCULARButton = uibutton(app.Panel, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.REAEditFieldLabel = uilabel(app.Panel);
            app.REAEditFieldLabel.Text = 'ÁREA:';
            app.RESULTADOEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Areatriangulorectan_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end