classdef CaidaLibre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LimpiarButton     matlab.ui.control.Button
        EditField         matlab.ui.control.EditField
        Gravedad981Label  matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        CalcularButton    matlab.ui.control.Button
        Label_5           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        SeleccionaapartirdequedatodeseashacerloscalculosLabel  matlab.ui.control.Label
        CAIDALIBRELabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
        g = 9.81;
        valorEntrada = str2double(app.EditField.Value);
        if isnan(valorEntrada) || valorEntrada < 0
            uialert(app.UIFigure, 'Por favor ingresa un número válido y positivo.', 'Entrada inválida');
            return;
        end

        opcion = app.DropDown_2.Value;

        switch opcion
            case 'Altura'
                h = valorEntrada;
                t = sqrt(2 * h / g);
                vf = g * t;

            case 'Tiempo de Caída'
                t = valorEntrada;
                h = 0.5 * g * t^2;
                vf = g * t;

            case 'Velocidad Final'
                vf = valorEntrada;
                t = vf / g;
                h = vf^2 / (2 * g);
        end

        app.Label_3.Text = sprintf('Altura: %.2f m', h);
        app.Label_4.Text = sprintf('Tiempo: %.2f s', t);
        app.Label_5.Text = sprintf('Velocidad final: %.2f m/s', vf);
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
    app.EditField.Value = '';
    app.Label_3.Text = 'Altura:';
    app.Label_4.Text = 'Tiempo:';
    app.Label_5.Text = 'Velocidad final:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CAIDALIBRELabel = uilabel(app.UIFigure);
            app.CAIDALIBRELabel.Text = 'CAIDA LIBRE';
            app.SeleccionaapartirdequedatodeseashacerloscalculosLabel = uilabel(app.UIFigure);
            app.SeleccionaapartirdequedatodeseashacerloscalculosLabel.Text = 'Selecciona a partir de que dato deseas hacer los calculos';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Altura', 'Tiempo de Caída', 'Velocidad Final'};
            app.DropDown_2.Value = 'Altura';
            app.Gravedad981Label = uilabel(app.UIFigure);
            app.Gravedad981Label.Text = 'Gravedad:9.81';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CaidaLibre

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end