classdef EcuDiferen_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        DatosPanel                      matlab.ui.container.Panel
        IngreselaEcuacinEditField       matlab.ui.control.EditField
        IngreselaEcuacinEditFieldLabel  matlab.ui.control.Label
        CondicinInicialEditField        matlab.ui.control.EditField
        CondicinInicialLabel            matlab.ui.control.Label
        ResultadosPanel                 matlab.ui.container.Panel
        ConcondicinyEditField           matlab.ui.control.EditField
        ConcondicinyEditFieldLabel      matlab.ui.control.Label
        SincondicinyEditField           matlab.ui.control.EditField
        SincondicinyEditFieldLabel      matlab.ui.control.Label
        Resolver                        matlab.ui.control.Button
        titulo                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Resolver
        function ResolverButtonPushed(app, event)
            try
                % Limpiar los campos de resultado
                app.SincondicinyEditField.Value = '';
                app.ConcondicinyEditField.Value = '';
                
                % Obtener la ecuación ingresada
                ecuacion_str = app.IngreselaEcuacinEditField.Value;
                condicion_str = app.CondicinInicialEditField.Value;
                
                % Validar que se haya ingresado una ecuación
                if isempty(ecuacion_str)
                    uialert(app.figure1, 'Por favor ingrese una ecuación diferencial', 'Error');
                    return;
                end
                
                % Limpiar workspace simbólico para evitar conflictos
                syms x y(x) C1 real
                assume(x, 'real');
                
                % Procesar la ecuación - convertir diferentes notaciones a formato estándar
                ecuacion_procesada = ecuacion_str;
                
                % Reemplazar notaciones de derivada por Dy
                ecuacion_procesada = strrep(ecuacion_procesada, 'dy/dx', 'Dy');
                ecuacion_procesada = strrep(ecuacion_procesada, 'y''', 'Dy');
                ecuacion_procesada = strrep(ecuacion_procesada, 'y´', 'Dy');
                ecuacion_procesada = strrep(ecuacion_procesada, "y'", 'Dy');
                
                % Asegurar que y se refiere a y(x)
                ecuacion_procesada = regexprep(ecuacion_procesada, '\by\b', 'y');
                
                disp(['Ecuación procesada: ', ecuacion_procesada]); % Para debug
                
                % Resolver la ecuación diferencial sin condición inicial
                try
                    % Usar dsolve con variables explícitas
                    solucion_general = dsolve(ecuacion_procesada, x);
                    
                    % Si no funciona, intentar método alternativo
                    if isempty(solucion_general)
                        solucion_general = dsolve(ecuacion_procesada);
                    end
                    
                    % Convertir a string y limpiar la notación
                    if isa(solucion_general, 'sym')
                        sol_general_str = char(solucion_general);
                        % Reemplazar y(x) por y para mejor visualización
                        sol_general_str = strrep(sol_general_str, 'y(x)', 'y');
                        % Asegurar que las constantes se muestren como C1, C2, etc.
                        sol_general_str = regexprep(sol_general_str, 'C\d+', 'C1');
                    else
                        sol_general_str = 'No se pudo resolver la ecuación';
                    end
                    
                    % Mostrar solución general
                    app.SincondicinyEditField.Value = sol_general_str;
                    
                catch ME
                    app.SincondicinyEditField.Value = ['Error: ' ME.message];
                    solucion_general = [];
                end
                
                % Resolver con condición inicial si se proporciona
                if ~isempty(condicion_str) && ~isempty(solucion_general)
                    try
                        % Procesar la condición inicial
                        condicion_procesada = condicion_str;
                        
                        % Asegurar formato correcto para dsolve
                        % Convertir y(0)=1 a formato apropiado
                        condicion_procesada = strrep(condicion_procesada, 'y(', 'y(');
                        
                        disp(['Condición procesada: ', condicion_procesada]); % Para debug
                        
                        % Resolver con condición inicial usando ambos argumentos
                        solucion_particular = dsolve(ecuacion_procesada, condicion_procesada, x);
                        
                        % Si no funciona, intentar método alternativo
                        if isempty(solucion_particular)
                            solucion_particular = dsolve(ecuacion_procesada, condicion_procesada);
                        end
                        
                        if isa(solucion_particular, 'sym') && ~isempty(solucion_particular)
                            sol_particular_str = char(solucion_particular);
                            % Limpiar la notación
                            sol_particular_str = strrep(sol_particular_str, 'y(x)', 'y');
                        else
                            sol_particular_str = 'No se pudo aplicar la condición inicial';
                        end
                        
                        app.ConcondicinyEditField.Value = sol_particular_str;
                        
                    catch ME
                        app.ConcondicinyEditField.Value = ['Error con condición: ' ME.message];
                    end
                elseif ~isempty(condicion_str)
                    app.ConcondicinyEditField.Value = 'Error en la ecuación principal';
                else
                    app.ConcondicinyEditField.Value = 'No se proporcionó condición inicial';
                end
                
            catch ME
                % Manejo de errores generales
                uialert(app.figure1, ['Error general: ' ME.message], 'Error');
                app.SincondicinyEditField.Value = 'Error en el procesamiento';
                app.ConcondicinyEditField.Value = 'Error en el procesamiento';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IneDiferen';
            app.figure1.Tag = 'figure1';
            app.titulo = uilabel(app.figure1);
            app.titulo.Tag = 'text2';
            app.titulo.Text = 'Ecuaciones Diferenciales';
            app.Resolver = uibutton(app.figure1, 'push');
            app.Resolver.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.Resolver.Tag = 'pushbutton1';
            app.Resolver.Text = 'Resolver ';
            app.ResultadosPanel = uipanel(app.figure1);
            app.ResultadosPanel.Title = 'Resultados';
            app.ResultadosPanel.Tag = 'uipanel1';
            app.SincondicinyEditFieldLabel = uilabel(app.ResultadosPanel);
            app.SincondicinyEditFieldLabel.Text = 'Sin condición: y=';
            app.SincondicinyEditField = uieditfield(app.ResultadosPanel, 'text');
            app.ConcondicinyEditFieldLabel = uilabel(app.ResultadosPanel);
            app.ConcondicinyEditFieldLabel.Text = 'Con condición: y=';
            app.ConcondicinyEditField = uieditfield(app.ResultadosPanel, 'text');
            app.DatosPanel = uipanel(app.figure1);
            app.DatosPanel.Title = 'Datos';
            app.CondicinInicialLabel = uilabel(app.DatosPanel);
            app.CondicinInicialLabel.Text = 'Condición Inicial:';
            app.CondicinInicialEditField = uieditfield(app.DatosPanel, 'text');
            app.IngreselaEcuacinEditFieldLabel = uilabel(app.DatosPanel);
            app.IngreselaEcuacinEditFieldLabel.Text = 'Ingrese la Ecuación: ';
            app.IngreselaEcuacinEditField = uieditfield(app.DatosPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuDiferen_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end