classdef LanzamientoDeProyectiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LimpiarButton              matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        CalcularButton             matlab.ui.control.Button
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        EditField_2                matlab.ui.control.EditField
        EditField                  matlab.ui.control.EditField
        nguloLabel                 matlab.ui.control.Label
        VelocidadInicialLabel      matlab.ui.control.Label
        LanzamientoParablicoLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
       
    g = 9.81;

    v0 = str2double(app.EditField.Value);         
    theta = str2double(app.EditField_2.Value);    

   
    if isnan(v0) || v0 < 0 || isnan(theta) || theta < 0 || theta > 90
        uialert(app.UIFigure, 'Por favor ingresa valores válidos: velocidad positiva y ángulo entre 0 y 90 grados.', 'Entrada inválida');
        return;
    end

    v0x = v0 * cosd(theta); 
    v0y = v0 * sind(theta); 


    h = (v0y^2) / (2 * g);      
    t = (2 * v0y) / g;     
    R = v0x * t;             

 
    app.Label.Text = sprintf('Altura máxima: %.2f m', h);
    app.Label_2.Text = sprintf('Tiempo total de vuelo: %.2f s', t);
    app.Label_3.Text = sprintf('Alcance horizontal: %.2f m', R);
    app.Label_4.Text = sprintf('Tiempo Hasta llegar a máxima altura: %.2f s', t/2);


        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            
   
    app.EditField.Value = '';
    app.EditField_2.Value = '';

    app.Label.Text = '';
    app.Label_2.Text = '';
    app.Label_3.Text = '';
    app.Label_4.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LanzamientoParablicoLabel = uilabel(app.UIFigure);
            app.LanzamientoParablicoLabel.Text = 'Lanzamiento Parabólico';
            app.VelocidadInicialLabel = uilabel(app.UIFigure);
            app.VelocidadInicialLabel.Text = 'Velocidad Inicial:';
            app.nguloLabel = uilabel(app.UIFigure);
            app.nguloLabel.Text = 'Ängulo:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LanzamientoDeProyectiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end