classdef MetodoGaussSeidel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        METODODEGAUSSSEIDELLabel   matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        RAIZTextArea               matlab.ui.control.TextArea
        RAIZTextAreaLabel          matlab.ui.control.Label
        LimpiarButton              matlab.ui.control.Button
        CalcularRaizButton         matlab.ui.control.Button
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        XoTextArea                 matlab.ui.control.TextArea
        XoTextAreaLabel            matlab.ui.control.Label
        VectorBTextArea            matlab.ui.control.TextArea
        VectorBTextAreaLabel       matlab.ui.control.Label
        MatrizATextArea            matlab.ui.control.TextArea
        MatrizATextAreaLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularRaizButton
        function CalcularRaizButtonPushed(app, event)
try
    % Limpiar salidas anteriores
    app.RAIZTextArea.Value = '';
    app.ListBox.Items = {};

    % Obtener entradas
    A = str2num(char(app.MatrizATextArea.Value)); %#ok<ST2NM>
    b = str2num(char(app.VectorBTextArea.Value)); %#ok<ST2NM>
    x = str2num(char(app.XoTextArea.Value));      %#ok<ST2NM>
    tol = app.ToleranciaEditField.Value;
    maxIter = app.IteracionesEditField.Value;

    % Validaciones (igual que antes)
    if isempty(A) || isempty(b) || isempty(x)
        app.RAIZTextArea.Value = "Error: Debes ingresar la matriz A, el vector b y x0.";
        return;
    end
    if size(A,1) ~= size(A,2) || size(A,1) ~= length(b)
        app.RAIZTextArea.Value = "Error: Dimensiones de A y b no compatibles.";
        return;
    end
    if length(x) ~= length(b)
        app.RAIZTextArea.Value = "Error: x0 debe tener la misma dimensión que b.";
        return;
    end
    if tol <= 0 || tol >= 1
        app.RAIZTextArea.Value = "Error: La tolerancia debe estar entre 0 y 1.";
        return;
    end
    if maxIter <= 0 || floor(maxIter) ~= maxIter
        app.RAIZTextArea.Value = "Error: Las iteraciones deben ser un entero positivo.";
        return;
    end

    % Validar que A sea diagonal dominante
    n = size(A,1);
    for i = 1:n
        suma_fila = sum(abs(A(i,:))) - abs(A(i,i));
        if abs(A(i,i)) <= suma_fila
            app.RAIZTextArea.Value = "Error: La matriz A no es diagonal dominante. Gauss-Seidel puede no converger.";
            return;
        end
    end

    % Asegurar vectores columna
    x = x(:);
    b = b(:);

    % Método Gauss-Seidel
    for k = 1:maxIter
        x_old = x;
        for i = 1:n
            if i > 1
                sum1 = A(i,1:i-1) * x(1:i-1);
            else
                sum1 = 0;
            end
            if i < n
                sum2 = A(i,i+1:n) * x_old(i+1:n);
            else
                sum2 = 0;
            end
            x(i) = (b(i) - sum1 - sum2) / A(i,i);
        end

        err = norm(x - x_old, inf);
        iter_msg = sprintf("Iteración %d: x = [%s], error = %.8f", k, num2str(x', '%.4f '), err);

        % Actualizar ListBox.Items correctamente
        items = string(app.ListBox.Items);
        items(end+1) = iter_msg;
        app.ListBox.Items = items;

        if err < tol
            app.RAIZTextArea.Value = sprintf("Solución encontrada: [%s]", num2str(x', '%.8f '));
            return;
        end
    end

    app.RAIZTextArea.Value = "No se alcanzó convergencia en " + string(maxIter) + " iteraciones.";

catch e
    app.RAIZTextArea.Value = "Error inesperado: " + e.message;
end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
% Limpiar campos de entrada
app.MatrizATextArea.Value = '';
app.VectorBTextArea.Value = '';
app.XoTextArea.Value = '';
app.ToleranciaEditField.Value = 0;
app.IteracionesEditField.Value = 0;

% Limpiar salidas
app.RAIZTextArea.Value = '';
app.ListBox.Items = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatrizATextAreaLabel = uilabel(app.UIFigure);
            app.MatrizATextAreaLabel.Text = 'Matriz A';
            app.MatrizATextArea = uitextarea(app.UIFigure);
            app.VectorBTextAreaLabel = uilabel(app.UIFigure);
            app.VectorBTextAreaLabel.Text = 'Vector B';
            app.VectorBTextArea = uitextarea(app.UIFigure);
            app.XoTextAreaLabel = uilabel(app.UIFigure);
            app.XoTextAreaLabel.Text = 'Xo';
            app.XoTextArea = uitextarea(app.UIFigure);
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularRaizButton = uibutton(app.UIFigure, 'push');
            app.CalcularRaizButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRaizButtonPushed, true);
            app.CalcularRaizButton.Text = 'Calcular Raiz';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.RAIZTextAreaLabel = uilabel(app.UIFigure);
            app.RAIZTextAreaLabel.Text = 'RAIZ';
            app.RAIZTextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.METODODEGAUSSSEIDELLabel = uilabel(app.UIFigure);
            app.METODODEGAUSSSEIDELLabel.Interpreter = 'tex';
            app.METODODEGAUSSSEIDELLabel.Text = 'METODO DE GAUSS-SEIDEL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoGaussSeidel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end