classdef MetodoMuller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        METODODEMULLERLabel        matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        RAIZTextArea               matlab.ui.control.TextArea
        RAIZTextAreaLabel          matlab.ui.control.Label
        LimpiarButton              matlab.ui.control.Button
        CalcularRaizButton         matlab.ui.control.Button
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        XoEditField                matlab.ui.control.NumericEditField
        XoEditFieldLabel           matlab.ui.control.Label
        FuncionEditField           matlab.ui.control.EditField
        FuncionEditFieldLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularRaizButton
        function CalcularRaizButtonPushed(app, event)
try
    % Limpiar salidas anteriores
    app.ListBox.Items = {};
    app.RAIZTextArea.Value = '';
    cla(app.UIAxes);

    % Obtener datos de los campos
    f_str = strtrim(app.FuncionEditField.Value);
    x0 = app.XoEditField.Value;
    x1 = app.X1EditField.Value;
    x2 = app.X2EditField.Value;
    tol = app.ToleranciaEditField.Value;
    maxIter = app.IteracionesEditField.Value;

    % Validaciones
    if isempty(f_str)
        app.RAIZTextArea.Value = "Error: Debe ingresar la función.";
        return;
    end
    if tol <= 0 || tol >= 1
        app.RAIZTextArea.Value = "Error: La tolerancia debe ser mayor que 0 y menor que 1.";
        return;
    end
    if maxIter <= 0 || floor(maxIter) ~= maxIter
        app.RAIZTextArea.Value = "Error: El máximo de iteraciones debe ser un entero positivo.";
        return;
    end

    % Convertir cadena a función anónima
    f = str2func(['@(x)', f_str]);

    % Probar que la función se pueda evaluar
    test_vals = [f(x0), f(x1), f(x2)];
    if any(isnan(test_vals)) || any(isinf(test_vals))
        error('La función no devuelve valores numéricos válidos.');
    end

    items = [];

    for k = 1:maxIter
        h1 = x1 - x0;
        h2 = x2 - x1;
        delta1 = (f(x1) - f(x0)) / h1;
        delta2 = (f(x2) - f(x1)) / h2;
        d = (delta2 - delta1) / (h2 + h1);

        b = delta2 + h2 * d;
        D = sqrt(b^2 - 4 * f(x2) * d);

        if b + D == 0 && b - D == 0
            app.RAIZTextArea.Value = "División por cero en iteración " + k;
            return;
        end

        if abs(b + D) > abs(b - D)
            den = b + D;
        else
            den = b - D;
        end

        dxr = -2 * f(x2) / den;
        xr = x2 + dxr;

        err = abs(dxr);
        iter_msg = sprintf("Iteración %d: x = %.8f, error = %.8f", k, xr, err);
        items = [items; iter_msg];  % acumular mensajes como string array

        if err < tol
            app.ListBox.Items = string(items);
            app.RAIZTextArea.Value = sprintf("Raíz encontrada: %.8f", xr);

            % Graficar la función y la raíz
            fplot(app.UIAxes, f, [xr-5, xr+5]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xr, f(xr), 'ro', 'MarkerSize', 10, 'DisplayName', 'Raíz');
            legend(app.UIAxes, 'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            return;
        end

        % Actualizar los puntos para la siguiente iteración
        x0 = x1;
        x1 = x2;
        x2 = xr;
    end

    app.ListBox.Items = string(items);
    app.RAIZTextArea.Value = "No se alcanzó la convergencia en " + maxIter + " iteraciones.";

catch e
    app.RAIZTextArea.Value = "Error inesperado: " + e.message;
end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
try
        % Limpiar campos de entrada
        app.FuncionEditField.Value = '';
        app.XoEditField.Value = 0;
        app.X1EditField.Value = 0;
        app.X2EditField.Value = 0;
        app.ToleranciaEditField.Value = 0.0001;
        app.IteracionesEditField.Value = 50;

        % Limpiar salidas
        app.RAIZTextArea.Value = '';
        app.ListBox.Items = {};
        cla(app.UIAxes); % Limpiar la gráfica
    catch e
        app.RAIZTextArea.Value = "Error al limpiar: " + e.message;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.XoEditFieldLabel = uilabel(app.UIFigure);
            app.XoEditFieldLabel.Text = 'Xo';
            app.XoEditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularRaizButton = uibutton(app.UIFigure, 'push');
            app.CalcularRaizButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRaizButtonPushed, true);
            app.CalcularRaizButton.Text = 'Calcular Raiz';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.RAIZTextAreaLabel = uilabel(app.UIFigure);
            app.RAIZTextAreaLabel.Text = 'RAIZ';
            app.RAIZTextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.METODODEMULLERLabel = uilabel(app.UIFigure);
            app.METODODEMULLERLabel.Interpreter = 'tex';
            app.METODODEMULLERLabel.Text = 'METODO DE MULLER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoMuller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end