classdef MetodoRaicesMultiples < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ListBox                        matlab.ui.control.ListBox
        RAIZTextArea                   matlab.ui.control.TextArea
        RAIZTextAreaLabel              matlab.ui.control.Label
        LimpiarButton                  matlab.ui.control.Button
        CalcularRaizButton             matlab.ui.control.Button
        IteracionesEditField           matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel      matlab.ui.control.Label
        ToleranciaEditField            matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel       matlab.ui.control.Label
        XoEditField                    matlab.ui.control.NumericEditField
        XoEditFieldLabel               matlab.ui.control.Label
        SegundaDerivadaEditField       matlab.ui.control.EditField
        SegundaDerivadaEditFieldLabel  matlab.ui.control.Label
        DerivadaEditField              matlab.ui.control.EditField
        DerivadaEditFieldLabel         matlab.ui.control.Label
        FuncionEditField               matlab.ui.control.EditField
        FuncionEditFieldLabel          matlab.ui.control.Label
        METODODERAICESMULTIPLESLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularRaizButton
        function CalcularRaizButtonPushed(app, event)
try
    % Limpiar salidas anteriores
    app.ListBox.Items = {};
    app.RAIZTextArea.Value = '';
    cla(app.UIAxes);

    % Obtener datos de los campos
    f_str = strtrim(app.FuncionEditField.Value);
    df_str = strtrim(app.DerivadaEditField.Value);
    d2f_str = strtrim(app.SegundaDerivadaEditField.Value);
    x0 = app.XoEditField.Value;
    tol = app.ToleranciaEditField.Value;   % <-- corregido aquí
    maxIter = app.IteracionesEditField.Value;

    % Validar que los campos no estén vacíos
    if isempty(f_str) || isempty(df_str) || isempty(d2f_str)
        app.RAIZTextArea.Value = "Error: Debe ingresar la función y sus derivadas.";
        return;
    end
    if tol <= 0 || tol >= 1
        app.RAIZTextArea.Value = "Error: La tolerancia debe ser mayor que 0 y menor que 1.";
        return;
    end
    if maxIter <= 0 || floor(maxIter) ~= maxIter
        app.RAIZTextArea.Value = "Error: El máximo de iteraciones debe ser un entero positivo.";
        return;
    end

    % Convertir cadenas a funciones anónimas
    f = str2func(['@(x)', f_str]);
    df = str2func(['@(x)', df_str]);
    d2f = str2func(['@(x)', d2f_str]);

    % Probar que las funciones se puedan evaluar
    test_vals = [f(x0), df(x0), d2f(x0)];
    if any(isnan(test_vals)) || any(isinf(test_vals))
        error('Las funciones no devuelven valores numéricos válidos.');
    end

    % Método de raíces múltiples
    for i = 1:maxIter
        fx = f(x0);
        dfx = df(x0);
        d2fx = d2f(x0);

        denom = dfx^2 - fx*d2fx;
        if denom == 0
            app.RAIZTextArea.Value = "División por cero en iteración " + i;
            return;
        end

        x1 = x0 - (fx * dfx) / denom;
        error = abs(x1 - x0);

        % Guardar iteración en ListBox
        iter_str = sprintf('Iteración %d: x = %.8f, error = %.8f', i, x1, error);
        app.ListBox.Items{end+1} = iter_str;

        if error < tol
            % Convergió
            app.RAIZTextArea.Value = sprintf('Raíz encontrada: %.8f', x1);

            % Graficar función y raíz
            fplot(app.UIAxes, f, [x1-5, x1+5]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x1, f(x1), 'ro', 'MarkerSize', 10, 'DisplayName', 'Raíz');
            legend(app.UIAxes, 'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            return;
        end

        x0 = x1;
    end

    % No convergió
    app.RAIZTextArea.Value = "No se alcanzó la convergencia en " + maxIter + " iteraciones.";

catch e
    app.RAIZTextArea.Value = "Error inesperado: " + e.message;
end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
% Limpiar campos de entrada
app.FuncionEditField.Value = '';
app.DerivadaEditField.Value = '';
app.SegundaDerivadaEditField.Value = '';
app.XoEditField.Value = 0;
app.ToleranciaEditField.Value = 0;   % <-- corregido aquí
app.IteracionesEditField.Value = 0;

% Limpiar resultados
app.RAIZTextArea.Value = '';
app.ListBox.Items = {};

% Limpiar gráfico
cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.METODODERAICESMULTIPLESLabel = uilabel(app.UIFigure);
            app.METODODERAICESMULTIPLESLabel.Interpreter = 'tex';
            app.METODODERAICESMULTIPLESLabel.Text = 'METODO DE RAICES MULTIPLES';
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.DerivadaEditFieldLabel = uilabel(app.UIFigure);
            app.DerivadaEditFieldLabel.Text = 'Derivada';
            app.DerivadaEditField = uieditfield(app.UIFigure, 'text');
            app.SegundaDerivadaEditFieldLabel = uilabel(app.UIFigure);
            app.SegundaDerivadaEditFieldLabel.Text = 'Segunda Derivada';
            app.SegundaDerivadaEditField = uieditfield(app.UIFigure, 'text');
            app.XoEditFieldLabel = uilabel(app.UIFigure);
            app.XoEditFieldLabel.Text = 'Xo';
            app.XoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularRaizButton = uibutton(app.UIFigure, 'push');
            app.CalcularRaizButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRaizButtonPushed, true);
            app.CalcularRaizButton.Text = 'Calcular Raiz';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.RAIZTextAreaLabel = uilabel(app.UIFigure);
            app.RAIZTextAreaLabel.Text = 'RAIZ';
            app.RAIZTextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoRaicesMultiples

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end