classdef NewtonRaphsonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditField                    matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        outputArea                   matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        tolInput                     matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel     matlab.ui.control.Label
        calcularButton               matlab.ui.control.Button
        x0Input                      matlab.ui.control.NumericEditField
        ValorinicialxEditFieldLabel  matlab.ui.control.Label
        fInput                       matlab.ui.control.EditField
        FuncinfxEditFieldLabel       matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcularButton
        function calcularButtonPushed(app, event)

try
    % Leer inputs
    f_str = app.fInput.Value;      % Ej: 'x.^2 - 2'
    x0 = app.x0Input.Value;        % Ej: 2
    tol = app.tolInput.Value;      % Ej: 1e-6
    maxIter = app.EditField.Value; % Este campo debe ser NUMERIC (no string)

    % Crear función anónima
    f = str2func(['@(x) ' f_str]);

    % Inicializar
    x = x0;
    iter = 0;
    error_val = inf;

    % Limpiar TextArea
    log = {}; % celda de strings
    x_vals = [];
    f_vals = [];

    while error_val > tol && iter < maxIter
        fx = f(x);
        h = 1e-6;
        dfx = (f(x + h) - f(x - h)) / (2*h);

        if dfx == 0
            log = {'Derivada cero, método falla.'};
            app.outputArea.Value = log;
            return;
        end

        x_new = x - fx / dfx;
        error_val = abs(x_new - x);
        x = x_new;
        iter = iter + 1;

        % Guardar resultado textual
        log{end+1,1} = sprintf('Iter %d: x = %.6f, f(x) = %.6f', iter, x, f(x));

        % Guardar para gráfica
        x_vals(end+1) = x;
        f_vals(end+1) = f(x);
    end

    % Resultado final
    log{end+1,1} = sprintf('Raíz aproximada: %.6f después de %d iteraciones', x, iter);
    app.outputArea.Value = log;

    % Graficar
    plot(app.UIAxes, x_vals, f_vals, '-o');
    title(app.UIAxes, 'Convergencia Newton-Raphson');
    xlabel(app.UIAxes, 'x');
    ylabel(app.UIAxes, 'f(x)');

catch ME
    app.outputArea.Value = {['Error: ' ME.message]};
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncinfxEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinfxEditFieldLabel.Text = 'Función f(x):	';
            app.fInput = uieditfield(app.UIFigure, 'text');
            app.ValorinicialxEditFieldLabel = uilabel(app.UIFigure);
            app.ValorinicialxEditFieldLabel.Text = 'Valor inicial x₀:	';
            app.x0Input = uieditfield(app.UIFigure, 'numeric');
            app.calcularButton = uibutton(app.UIFigure, 'push');
            app.calcularButton.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcularButton.Text = 'Calcular';
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.tolInput = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.outputArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'iteracions maximas';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRaphsonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end