classdef ProdNota_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        SOLOEXPANDEBINOMIOSPanel        matlab.ui.container.Panel
        CuadradodeunBinomioDiferenciadeCuadradosLabel  matlab.ui.control.Label
        IngreselaEcuacinEditField       matlab.ui.control.EditField
        IngreselaEcuacinEditFieldLabel  matlab.ui.control.Label
        RESULTADOEditField              matlab.ui.control.EditField
        RESULTADOEditFieldLabel         matlab.ui.control.Label
        CALCULARButton                  matlab.ui.control.Button
        titulo                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            try
                % Obtener la expresión ingresada por el usuario
                expresion = app.IngreselaEcuacinEditField.Value;
                disp(['Expresión ingresada: ', expresion]);  % Depuración
        
                % Detectar variables simbólicas automáticamente
                variables = symvar(expresion);
                if isempty(variables)
                    variables = sym('x');  % Valor por defecto si no se detectan variables
                else
                    syms(variables{:});  % Crear simbólicas automáticamente
                end
        
                % Convertir la cadena a expresión simbólica
                exprSimbolica = str2sym(expresion);
                disp(['Expresión simbólica: ', char(exprSimbolica)]);  % Depuración
        
                % Expandir la expresión
                resultadoExpandido = expand(exprSimbolica);
                disp(['Resultado expandido: ', char(resultadoExpandido)]);  % Depuración
        
                % Mostrar el resultado en el campo de texto
                app.RESULTADOEditField.Value = char(resultadoExpandido);
        
            catch ME
                % Mostrar mensaje de error
                app.RESULTADOEditField.Value = 'Error: Expresión no válida';
                disp(['Error: ', ME.message]);  % Depuración
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PRODUCTOS NOTABLES';
            app.figure1.Tag = 'figure1';
            app.titulo = uilabel(app.figure1);
            app.titulo.Tag = 'text2';
            app.titulo.Text = 'Calculadora de Productos Notables ';
            app.SOLOEXPANDEBINOMIOSPanel = uipanel(app.figure1);
            app.SOLOEXPANDEBINOMIOSPanel.Title = 'SOLO EXPANDE BINOMIOS';
            app.CALCULARButton = uibutton(app.SOLOEXPANDEBINOMIOSPanel, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.RESULTADOEditFieldLabel = uilabel(app.SOLOEXPANDEBINOMIOSPanel);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO: ';
            app.RESULTADOEditField = uieditfield(app.SOLOEXPANDEBINOMIOSPanel, 'text');
            app.IngreselaEcuacinEditFieldLabel = uilabel(app.SOLOEXPANDEBINOMIOSPanel);
            app.IngreselaEcuacinEditFieldLabel.Text = 'Ingrese la Ecuación: ';
            app.IngreselaEcuacinEditField = uieditfield(app.SOLOEXPANDEBINOMIOSPanel, 'text');
            app.CuadradodeunBinomioDiferenciadeCuadradosLabel = uilabel(app.SOLOEXPANDEBINOMIOSPanel);
            app.CuadradodeunBinomioDiferenciadeCuadradosLabel.Text = 'Cuadrado de un Binomio, Diferencia de Cuadrados,Producto de Binomios Conjugados,Binomio al Cubo';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProdNota_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end