classdef SegundLeyNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LimpiarButton            matlab.ui.control.Button
        CalcularButton           matlab.ui.control.Button
        LabelRes                 matlab.ui.control.Label
        DejalacasilladelvalorquedeseascalcularvaciaLabel  matlab.ui.control.Label
        FuerzaLabel_2            matlab.ui.control.Label
        EditFieldAce             matlab.ui.control.EditField
        EditFieldMasa            matlab.ui.control.EditField
        EditFieldFuerza          matlab.ui.control.EditField
        AceleracinLabel          matlab.ui.control.Label
        MasaLabel                matlab.ui.control.Label
        FuerzaLabel              matlab.ui.control.Label
        SegundaLeyDeNewtonLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
    F = str2double(app.EditFieldFuerza.Value);
    m = str2double(app.EditFieldMasa.Value);
    a = str2double(app.EditFieldAce.Value);

   
    if isnan(F) && ~isnan(m) && ~isnan(a)
      
        F = m * a;
        app.LabelRes.Text = sprintf('Fuerza = %.2f N', F);

    elseif isnan(m) && ~isnan(F) && ~isnan(a)
    
        if a == 0
            uialert(app.UIFigure, 'La aceleración no puede ser cero.', 'Error');
            return;
        end
        m = F / a;
        app.LabelRes.Text = sprintf('Masa = %.2f kg', m);

    elseif isnan(a) && ~isnan(F) && ~isnan(m)
    
        if m == 0
            uialert(app.UIFigure, 'La masa no puede ser cero.', 'Error');
            return;
        end
        a = F / m;
        app.LabelRes.Text = sprintf('Aceleración = %.2f m/s²', a);
    else    
        uialert(app.UIFigure, 'Deja vacío solo el valor que deseas calcular.', 'Entrada inválida');
    end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
                app.EditFieldFuerza.Value = '';
    app.EditFieldMasa.Value = '';
    app.EditFieldAce.Value = '';
    app.LabelRes.Text = ''; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SegundaLeyDeNewtonLabel = uilabel(app.UIFigure);
            app.SegundaLeyDeNewtonLabel.Text = 'Segunda Ley De Newton';
            app.FuerzaLabel = uilabel(app.UIFigure);
            app.FuerzaLabel.Text = 'Fuerza';
            app.MasaLabel = uilabel(app.UIFigure);
            app.MasaLabel.Text = 'Masa';
            app.AceleracinLabel = uilabel(app.UIFigure);
            app.AceleracinLabel.Text = 'Aceleración';
            app.EditFieldFuerza = uieditfield(app.UIFigure, 'text');
            app.EditFieldMasa = uieditfield(app.UIFigure, 'text');
            app.EditFieldAce = uieditfield(app.UIFigure, 'text');
            app.FuerzaLabel_2 = uilabel(app.UIFigure);
            app.FuerzaLabel_2.Text = {'"La aceleración de un objeto es directamente proporcional'; ' a la fuerza neta que actúa sobre él, e inversamente proporcional '; 'a su masa."'};
            app.DejalacasilladelvalorquedeseascalcularvaciaLabel = uilabel(app.UIFigure);
            app.DejalacasilladelvalorquedeseascalcularvaciaLabel.Text = 'Deja la casilla del valor que deseas calcular vacia';
            app.LabelRes = uilabel(app.UIFigure);
            app.LabelRes.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegundLeyNewton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end