classdef TerceraLeyDeNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LimpiarButton                matlab.ui.control.Button
        CalcularButton               matlab.ui.control.Button
        Labelres                     matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        IngresaunafuerzavalidaLabel  matlab.ui.control.Label
        FuerzaLabel                  matlab.ui.control.Label
        TerceraLeyDeNewtonLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
       
    F = str2double(app.EditField.Value);

    if isnan(F)
        uialert(app.UIFigure, 'Ingresa una fuerza válida (un número).', 'Error');
        return;
    end

    R = -F;
 
    app.Labelres.Text = sprintf('Fuerza de reacción es: %.2f N', R);

        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
   
            app.EditField.Value = '';
            app.Labelres.Text = '';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TerceraLeyDeNewtonLabel = uilabel(app.UIFigure);
            app.TerceraLeyDeNewtonLabel.Text = 'Tercera Ley De Newton';
            app.FuerzaLabel = uilabel(app.UIFigure);
            app.FuerzaLabel.Text = '"A toda acción corresponde una reacción de igual magnitud y en sentido opuesto."';
            app.IngresaunafuerzavalidaLabel = uilabel(app.UIFigure);
            app.IngresaunafuerzavalidaLabel.Text = 'Ingresa una fuerza valida';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Labelres = uilabel(app.UIFigure);
            app.Labelres.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TerceraLeyDeNewton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end