classdef TiroVertical < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        LimpiarButton      matlab.ui.control.Button
        CalcularButton     matlab.ui.control.Button
        EditField          matlab.ui.control.EditField
        SeleccionaapartirdequedatodeseashacerloscalculosLabel  matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Gravedad981Label   matlab.ui.control.Label
        TiroVerticalLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
    g = 9.81;
    opcion = app.DropDown.Value;
    entrada = str2double(app.EditField.Value);

    if isnan(entrada) || entrada < 0
        uialert(app.UIFigure, 'Por favor ingresa un número válido y positivo.', 'Entrada inválida');
        return;
    end

    switch opcion
        case 'Velocidad Inicial'
            v0 = entrada;
            t = v0 / g;
            h = (v0^2) / (2 * g);

        case 'Tiempo'
            t = entrada;
            v0 = g * t;
            h = 0.5 * g * t^2;

        case 'Altura'
            h = entrada;
            v0 = sqrt(2 * g * h);
            t = v0 / g;
    end


    app.Label.Text = sprintf('Velocidad inicial: %.2f m/s', v0);
    app.Label_2.Text = sprintf('Tiempo: %.2f s', t);
    app.Label_3.Text = sprintf('Altura máxima: %.2f m', h);

        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
                app.EditField.Value = '';

    app.Label_3.Text = 'Altura:';
    app.Label_2.Text = 'Tiempo:';
    app.Label.Text = 'Velocidad Inicial:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TiroVerticalLabel = uilabel(app.UIFigure);
            app.TiroVerticalLabel.Text = 'Tiro Vertical';
            app.Gravedad981Label = uilabel(app.UIFigure);
            app.Gravedad981Label.Text = 'Gravedad:9.81';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Altura', 'Tiempo', 'Velocidad Inicial'};
            app.DropDown.Value = 'Altura';
            app.SeleccionaapartirdequedatodeseashacerloscalculosLabel = uilabel(app.UIFigure);
            app.SeleccionaapartirdequedatodeseashacerloscalculosLabel.Text = 'Selecciona a partir de que dato deseas hacer los calculos';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiroVertical

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end