classdef VELOCIDAD_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        panel                    matlab.ui.container.Panel
        TIEMPODropDown           matlab.ui.control.DropDown
        DISTANCIADropDown        matlab.ui.control.DropDown
        VELOCIDADEditField       matlab.ui.control.EditField
        VELOCIDADEditFieldLabel  matlab.ui.control.Label
        TIEMPOEditField          matlab.ui.control.NumericEditField
        TIEMPOEditFieldLabel     matlab.ui.control.Label
        DISTANCIAEditField       matlab.ui.control.NumericEditField
        DISTANCIAEditFieldLabel  matlab.ui.control.Label
        CALCULAR                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULAR
        function CALCULARButtonPushed(app, event)
             % Obtener valores de entrada
            distancia = app.DISTANCIAEditField.Value;
            tiempo = app.TIEMPOEditField.Value;
            unidadDistancia = app.DISTANCIADropDown.Value;
            unidadTiempo = app.TIEMPODropDown.Value;
        
            % Verificación
            if tiempo <= 0
                uialert(app.figure1, 'El tiempo debe ser mayor que cero.', 'Error');
                return;
            end
        
            % Convertir distancia a metros
            switch unidadDistancia
                case 'km'
                    distancia_m = distancia * 1000;
                case 'm'
                    distancia_m = distancia;
                otherwise
                    distancia_m = distancia;
            end
        
            % Convertir tiempo a segundos
            switch unidadTiempo
                case 'h'
                    tiempo_s = tiempo * 3600;
                case 'min'
                    tiempo_s = tiempo * 60;
                case 's'
                    tiempo_s = tiempo;
                otherwise
                    tiempo_s = tiempo;
            end
        
            % Calcular velocidad en m/s
            velocidad_ms = distancia_m / tiempo_s;
        
            % Mostrar velocidad en el campo de texto
            app.VELOCIDADEditField.Value = sprintf('%.2f m/s', velocidad_ms);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'VELOCIDAD';
            app.figure1.Tag = 'figure1';
            app.panel = uipanel(app.figure1);
            app.panel.Title = 'CALCULO DE VELOCIDAD';
            app.panel.Tag = 'uipanel1';
            app.CALCULAR = uibutton(app.panel, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULAR.Tag = 'pushbutton1';
            app.CALCULAR.Text = 'CALCULAR';
            app.DISTANCIAEditFieldLabel = uilabel(app.panel);
            app.DISTANCIAEditFieldLabel.Text = 'DISTANCIA: ';
            app.DISTANCIAEditField = uieditfield(app.panel, 'numeric');
            app.TIEMPOEditFieldLabel = uilabel(app.panel);
            app.TIEMPOEditFieldLabel.Text = 'TIEMPO:';
            app.TIEMPOEditField = uieditfield(app.panel, 'numeric');
            app.VELOCIDADEditFieldLabel = uilabel(app.panel);
            app.VELOCIDADEditFieldLabel.Text = 'VELOCIDAD: ';
            app.VELOCIDADEditField = uieditfield(app.panel, 'text');
            app.DISTANCIADropDown = uidropdown(app.panel);
            app.DISTANCIADropDown.Items = {'m', 'km'};
            app.DISTANCIADropDown.Value = 'm';
            app.TIEMPODropDown = uidropdown(app.panel);
            app.TIEMPODropDown.Items = {'s', 'min', 'h'};
            app.TIEMPODropDown.Value = 's';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VELOCIDAD_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end