classdef masOpciones_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        CERRAR      matlab.ui.control.Button
        text3       matlab.ui.control.Label
        popupmenu1  matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function masOpciones_OpeningFcn(app, varargin)
            % --- Executes just before masOpciones is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>


            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to masOpciones (see VARARGIN)
            
            % Choose default command line output for masOpciones
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: CERRAR
        function CERRAR_Callback(app, event)
            % --- Executes on button press in CERRAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Cientifica
            close(masOpciones)
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            option=get(handles.popupmenu1,'value');
            switch (option)
                case 1
                case 2
                     PortadaPrecalculo
                case 3
                   PortadaCalculo
                case 4
                    PortadaFisica
                case 5
                    PortadaEstadistica
                case 6
                    PortadaAlgebra
                case 7
                    PortadaMetodos
                case 8
                    PortadaGeometr      ia
                case 9
                    PortadaMateFinanciera
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'masOpciones';
            app.figure1.Tag = 'figure1';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Seleccionar', 'Pre-cálculo', 'Cálculo', 'Física', 'Estadística', 'Algebra Lineal', 'Métodos Numéricos', 'Geometría', 'Matemática Financiera'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Seleccionar';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'SELECCIONE LA MATERIA CON LA QUE DESEA TRABAJAR';
            app.CERRAR = uibutton(app.figure1, 'push');
            app.CERRAR.ButtonPushedFcn = createCallbackFcn(app, @CERRAR_Callback, true);
            app.CERRAR.Tag = 'CERRAR';
            app.CERRAR.Text = 'X';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = masOpciones_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)masOpciones_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end