classdef ProdNota_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        uipanel1   matlab.ui.container.Panel
        resultado  matlab.ui.control.Label
        resolver   matlab.ui.control.Button
        edit1      matlab.ui.control.EditField
        text3      matlab.ui.control.Label
        text2      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ProdNota_OpeningFcn(app, varargin)
           % --- Executes just before ProdNota is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
        end

        % Button pushed function: resolver
        function resolver_Callback(app, event)
          % --- Executes on button press in resolver.
            
            try
                % Obtiene el valor del campo de texto
                expresion = app.edit1.Value;
                disp(['Expresión ingresada: ', expresion]); % Depuración

                % Convierte la expresión en una expresión simbólica
                syms x y;
                a = str2sym(expresion);
                disp(['Expresión simbólica: ', char(a)]); % Depuración

                % Expande la expresión (para productos notables)
                c = expand(a); % Usamos expand en lugar de factor
                disp(['Resultado de la expansión: ', char(c)]); % Depuración

                % Actualiza el texto de la etiqueta resultado
                app.resultado.Text = char(c);
            catch ME
                % Si hay un error, muestra un mensaje en el label
                app.resultado.Text = 'Error: Expresión no válida';
                disp(['Error: ', ME.message]); % Depuración
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ProdNota';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Calculadora de Producto Notables *(Binomios)';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ingrese la ecuación';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.Tag = 'edit1';
            app.resolver = uibutton(app.figure1, 'push');
            app.resolver.ButtonPushedFcn = createCallbackFcn(app, @resolver_Callback, true);
            app.resolver.Tag = 'resolver';
            app.resolver.Text = 'Resolver';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Resultado';
            app.uipanel1.Tag = 'uipanel1';
            app.resultado = uilabel(app.uipanel1);
            app.resultado.Tag = 'resultado';
            app.resultado.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProdNota_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ProdNota_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end