classdef Secante_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        DATOSINICIALESPanel  matlab.ui.container.Panel
        Error                matlab.ui.control.EditField
        sEditFieldLabel      matlab.ui.control.Label
        RAIZEditField        matlab.ui.control.EditField
        RAIZLabel            matlab.ui.control.Label
        X0EditField          matlab.ui.control.NumericEditField
        X0Label              matlab.ui.control.Label
        X1EditField          matlab.ui.control.NumericEditField
        X1EditFieldLabel     matlab.ui.control.Label
        fxEditField          matlab.ui.control.EditField
        fxLabel              matlab.ui.control.Label
        TITULO               matlab.ui.control.Label
        CalcularRaiz         matlab.ui.control.Button
        iteraciones          matlab.ui.control.ListBox
        grafica              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularRaiz
        function CalcularRaizButtonPushed(app, event)
            % Obtener los valores ingresados por el usuario
            fx_str = app.fxEditField.Value;
            x0 = app.X0EditField.Value;
            x1 = app.X1EditField.Value;
                        
            % Obtener el valor de Ɛs desde el campo de texto
            tol = str2double(app.Error.Value);
            maxIter = 100;
            
            % Validar que tol sea un número positivo
            if isnan(tol) || tol <= 0
                uialert(app.figure1, 'Por favor, ingrese un valor numérico positivo para Ɛs.', 'Error de tolerancia');
                return;
            end
            
            % Convertir string a función anónima
            try
                f = str2func(['@(x)', fx_str]);
                % Validar con un ejemplo
                f(x0);
            catch
                uialert(app.figure1, 'La función ingresada no es válida.', 'Error de función');
                return;
            end
        
            % Limpiar la ListBox
            app.iteraciones.Items = {'Iteraciones:'};
            
            % Iteración del método de la secante
            iter = 0;
            error = abs(x1 - x0);
            
            while error > tol && iter < maxIter
                iter = iter + 1;
                try
                    fx0 = f(x0);
                    fx1 = f(x1);
                    x2 = x1 - fx1 * (x1 - x0) / (fx1 - fx0);
                catch
                    uialert(app.figure1, 'Error en el cálculo de la función. Revisa los valores.', 'Error');
                    return;
                end
                
                % Mostrar iteración
                app.iteraciones.Items{end+1} = sprintf('Iter %d: x = %.8f', iter, x2);
                
                % Actualizar valores para la siguiente iteración
                error = abs(x2 - x1);
                x0 = x1;
                x1 = x2;
            end
            
            % Mostrar resultado en campo de raíz
            app.RAIZEditField.Value = num2str(x2, '%.10f');
            
            % Graficar la función y raíz
            try
                x_vals = linspace(x2 - 5, x2 + 5, 1000);
                y_vals = arrayfun(f, x_vals);
                plot(app.grafica, x_vals, y_vals, 'b-', 'LineWidth', 2);
                hold(app.grafica, 'on');
                plot(app.grafica, x2, f(x2), 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
                yline(app.grafica, 0, '--k');
                title(app.grafica, 'Gráfica de f(x) y la raíz encontrada');
                xlabel(app.grafica, 'x');
                ylabel(app.grafica, 'f(x)');
                grid(app.grafica, 'on');
                hold(app.grafica, 'off');
            catch
                uialert(app.figure1, 'No se pudo graficar la función.', 'Error de gráfico');
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Secante';
            app.figure1.Tag = 'figure1';
            app.grafica = uiaxes(app.figure1);
            app.grafica.Tag = 'grafica';
            app.iteraciones = uilistbox(app.figure1);
            app.iteraciones.Items = {'Iteraciones'};
            app.iteraciones.Tag = 'iteraciones';
            app.iteraciones.Value = 'Iteraciones';
            app.CalcularRaiz = uibutton(app.figure1, 'push');
            app.CalcularRaiz.ButtonPushedFcn = createCallbackFcn(app, @CalcularRaizButtonPushed, true);
            app.CalcularRaiz.Tag = 'ejecutar';
            app.CalcularRaiz.Text = 'Calcular Raiz';
            app.TITULO = uilabel(app.figure1);
            app.TITULO.Tag = 'text8';
            app.TITULO.Text = 'MÉTODO DE LA SECANTE';
            app.DATOSINICIALESPanel = uipanel(app.figure1);
            app.DATOSINICIALESPanel.Title = 'DATOS INICIALES:';
            app.fxLabel = uilabel(app.DATOSINICIALESPanel);
            app.fxLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.DATOSINICIALESPanel, 'text');
            app.X1EditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.X1EditFieldLabel.Text = 'X-1=';
            app.X1EditField = uieditfield(app.DATOSINICIALESPanel, 'numeric');
            app.X0Label = uilabel(app.DATOSINICIALESPanel);
            app.X0Label.Text = 'X0=';
            app.X0EditField = uieditfield(app.DATOSINICIALESPanel, 'numeric');
            app.RAIZLabel = uilabel(app.DATOSINICIALESPanel);
            app.RAIZLabel.Text = 'RAIZ=';
            app.RAIZEditField = uieditfield(app.DATOSINICIALESPanel, 'text');
            app.sEditFieldLabel = uilabel(app.DATOSINICIALESPanel);
            app.sEditFieldLabel.Text = 'Ɛs=';
            app.Error = uieditfield(app.DATOSINICIALESPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Secante_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end