classdef VELOCIDAD_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function VELOCIDAD_OpeningFcn(app, varargin)
            % --- Executes just before VELOCIDAD is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            axes('Units','Normalized','Position',[0 0 1 1]);
            
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Val=get(hObject,'string');
            NewVal=str2double(Val);
            handles.edit1=NewVal;
            guidata(hObject,handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Val=get(hObject,'string');
            NewVal=str2double(Val);
            handles.edit2=NewVal;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            A=handles.edit1;
            B=handles.edit2;
            
            div=A/B;
            set(handles.text5,'string',div);
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            % Limpiar los campos de entrada
            app.edit1.Value = '';
            app.edit2.Value = '';
            
            % También puedes limpiar el resultado mostrado, si deseas
            app.text5.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'VELOCIDAD';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'CALCULO DE VELOCIDAD';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'DISTANCIA';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'TIEMPO (S)';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'CALCULAR';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'VELOCIDAD';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = '';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'LIMPIAR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VELOCIDAD_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)VELOCIDAD_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end