classdef integrales_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        Titulo                        matlab.ui.control.Label
        uipanel1                      matlab.ui.container.Panel
        IntegralDefinidaSwitch        matlab.ui.control.Switch
        IntegralDefinidaSwitchLabel   matlab.ui.control.Label
        LmiteInferiorEditField        matlab.ui.control.NumericEditField
        LmiteInferiorEditFieldLabel   matlab.ui.control.Label
        LimiteSuperiorEditField       matlab.ui.control.NumericEditField
        LimiteSuperiorEditFieldLabel  matlab.ui.control.Label
        IntegrarButton                matlab.ui.control.Button
        IntegralEditField             matlab.ui.control.EditField
        IntegralEditFieldLabel        matlab.ui.control.Label
        fxEditField                   matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IntegrarButton
        function IntegrarButtonPushed(app, event)
            try
                syms x
                funcStr = app.fxEditField.Value;
                func = str2sym(funcStr); % Convertir a simbólico
        
                if strcmp(app.IntegralDefinidaSwitch.Value, 'SIMON')
                    a = app.LmiteInferiorEditField.Value;
                    b = app.LimiteSuperiorEditField.Value;
        
                    % Verificación adicional opcional:
                    if isnan(a) || isnan(b)
                        app.IntegralEditField.Value = 'Límites inválidos';
                        return;
                    end
        
                    result = int(func, x, a, b); % Integral definida
                else
                    result = int(func, x); % Integral indefinida
                end
        
                app.IntegralEditField.Value = char(result); % Mostrar resultado
        
            catch e
                app.IntegralEditField.Value = 'Error en la función';
                disp(['Error al calcular la integral: ' e.message])
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Integrales';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.Label = uilabel(app.uipanel1);
            app.Label.Text = '∫f(x)=';
            app.fxEditField = uieditfield(app.uipanel1, 'text');
            app.IntegralEditFieldLabel = uilabel(app.uipanel1);
            app.IntegralEditFieldLabel.Text = 'Integral: ';
            app.IntegralEditField = uieditfield(app.uipanel1, 'text');
            app.IntegrarButton = uibutton(app.uipanel1, 'push');
            app.IntegrarButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButtonPushed, true);
            app.IntegrarButton.Tag = 'pushbutton1';
            app.IntegrarButton.Text = 'INTEGRAR';
            app.LimiteSuperiorEditFieldLabel = uilabel(app.uipanel1);
            app.LimiteSuperiorEditFieldLabel.Text = 'Limite Superior: ';
            app.LimiteSuperiorEditField = uieditfield(app.uipanel1, 'numeric');
            app.LmiteInferiorEditFieldLabel = uilabel(app.uipanel1);
            app.LmiteInferiorEditFieldLabel.Text = 'Límite Inferior: ';
            app.LmiteInferiorEditField = uieditfield(app.uipanel1, 'numeric');
            app.IntegralDefinidaSwitchLabel = uilabel(app.uipanel1);
            app.IntegralDefinidaSwitchLabel.Text = '¿Integral Definida?';
            app.IntegralDefinidaSwitch = uiswitch(app.uipanel1, 'slider');
            app.IntegralDefinidaSwitch.Items = {'NEL', 'SIMON'};
            app.IntegralDefinidaSwitch.Value = 'NEL';
            app.Titulo = uilabel(app.figure1);
            app.Titulo.Tag = 'text6';
            app.Titulo.Text = 'INTEGRALES';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrales_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end