classdef matriz_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        CALCULOSPanel            matlab.ui.container.Panel
        RESULTADOEditField       matlab.ui.control.EditField
        RESULTADOEditFieldLabel  matlab.ui.control.Label
        DETERMINANTE             matlab.ui.control.Button
        INVERSA                  matlab.ui.control.Button
        LIMPIAR                  matlab.ui.control.Button
        TMatrizPanel             matlab.ui.container.Panel
        insertar                 matlab.ui.control.Button
        ColumnasEditField        matlab.ui.control.NumericEditField
        ColumnasEditFieldLabel   matlab.ui.control.Label
        FilasEditField           matlab.ui.control.NumericEditField
        FilasEditFieldLabel      matlab.ui.control.Label
        aviso                    matlab.ui.control.Label
        matriz                   matlab.ui.control.Table
        titulo                   matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: insertar
        function insertarButtonPushed(app, event)
            filas = app.FilasEditField.Value;
            columnas = app.ColumnasEditField.Value;
        
            if filas ~= columnas
                uialert(app.figure1, 'La matriz debe ser cuadrada.', 'Error');
                return;
            end
        
            if filas > 4 || columnas > 4
                uialert(app.figure1, 'Solo se permiten matrices de hasta 4x4.', 'Límite excedido');
                return;
            end
        
            app.matriz.Data = zeros(filas, columnas);
            app.matriz.ColumnEditable = true(1, columnas);
            app.matriz.ColumnName = repmat({''}, 1, columnas); % sin encabezado
            app.matriz.RowName = {};            
        end

        % Button pushed function: DETERMINANTE
        function DETERMINANTEButtonPushed(app, event)
            A = app.matriz.Data;
        
            [filas, columnas] = size(A);
            if filas ~= columnas
                app.RESULTADOEditField.Value = 'La matriz no es cuadrada.';
                return;
            end
        
            try
                detA = det(A);
                app.RESULTADOEditField.Value = ['Determinante: ', num2str(detA)];
            catch
                app.RESULTADOEditField.Value = 'Error al calcular el determinante.';
            end            
        end

        % Button pushed function: INVERSA
        function INVERSAButtonPushed(app, event)
            A = app.matriz.Data;
        
            [filas, columnas] = size(A);
            if filas ~= columnas
                app.RESULTADOEditField.Value = 'La matriz no es cuadrada.';
                return;
            end
        
            if det(A) == 0
                app.RESULTADOEditField.Value = 'La matriz no es invertible.';
                return;
            end
        
            try
                invA = inv(A);
                % Mostrar la inversa en formato texto
                texto = "Inversa:\n";
                for i = 1:size(invA, 1)
                    texto = texto + sprintf('%s\n', num2str(invA(i, :), '%.3f\t'));
                end
                app.RESULTADOEditField.Value = sprintf(texto);
            catch
                app.RESULTADOEditField.Value = 'Error al calcular la inversa.';
            end            
        end

        % Button pushed function: LIMPIAR
        function LIMPIARButtonPushed(app, event)
            app.matriz.Data = {};
            app.RESULTADOEditField.Value = '';
            app.FilasEditField.Value = 0;
            app.ColumnasEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'matriz';
            app.figure1.Tag = 'figure1';
            app.titulo = uilabel(app.figure1);
            app.titulo.Tag = 'text2';
            app.titulo.Text = 'CALCULADORA DE MATRICES';
            app.matriz = uitable(app.figure1);
            app.matriz.ColumnEditable = [false false];
            app.matriz.Tag = 'uitable1';
            app.aviso = uilabel(app.figure1);
            app.aviso.Tag = 'text6';
            app.aviso.Text = 'SOLO 4*4';
            app.TMatrizPanel = uipanel(app.figure1);
            app.TMatrizPanel.Title = 'TAMAÑO DE LA MATRIZ';
            app.FilasEditFieldLabel = uilabel(app.TMatrizPanel);
            app.FilasEditFieldLabel.Text = 'Filas: ';
            app.FilasEditField = uieditfield(app.TMatrizPanel, 'numeric');
            app.ColumnasEditFieldLabel = uilabel(app.TMatrizPanel);
            app.ColumnasEditFieldLabel.Text = 'Columnas: ';
            app.ColumnasEditField = uieditfield(app.TMatrizPanel, 'numeric');
            app.insertar = uibutton(app.TMatrizPanel, 'push');
            app.insertar.ButtonPushedFcn = createCallbackFcn(app, @insertarButtonPushed, true);
            app.insertar.Tag = 'insertar';
            app.insertar.Text = 'Insertar';
            app.CALCULOSPanel = uipanel(app.figure1);
            app.CALCULOSPanel.Title = 'CALCULOS';
            app.LIMPIAR = uibutton(app.CALCULOSPanel, 'push');
            app.LIMPIAR.ButtonPushedFcn = createCallbackFcn(app, @LIMPIARButtonPushed, true);
            app.LIMPIAR.Tag = 'limpiar';
            app.LIMPIAR.Text = 'LIMPIAR';
            app.INVERSA = uibutton(app.CALCULOSPanel, 'push');
            app.INVERSA.ButtonPushedFcn = createCallbackFcn(app, @INVERSAButtonPushed, true);
            app.INVERSA.Text = 'INVERSA';
            app.DETERMINANTE = uibutton(app.CALCULOSPanel, 'push');
            app.DETERMINANTE.ButtonPushedFcn = createCallbackFcn(app, @DETERMINANTEButtonPushed, true);
            app.DETERMINANTE.Text = 'DETERMINANTE';
            app.RESULTADOEditFieldLabel = uilabel(app.CALCULOSPanel);
            app.RESULTADOEditFieldLabel.Text = 'RESULTADO: ';
            app.RESULTADOEditField = uieditfield(app.CALCULOSPanel, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matriz_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end